classdef VisualizeAntennaCoverage_test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup3                       matlab.ui.container.TabGroup
        SwarmPropertiesTab              matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        RecieverSitesListBox            matlab.ui.control.ListBox
        RecieverSitesLabel              matlab.ui.control.Label
        TransmitterSitesListBox         matlab.ui.control.ListBox
        TransmitterSitesListBoxLabel    matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        DirectionalAntennaTab           matlab.ui.container.Tab
        TiltAxisDropDown                matlab.ui.control.DropDown
        TiltAxisDropDownLabel           matlab.ui.control.Label
        PlotDirectivityPatternButton    matlab.ui.control.Button
        TiltAngleEditField              matlab.ui.control.NumericEditField
        TiltAngleEditFieldLabel         matlab.ui.control.Label
        PlotIdealcoverageAreaButton     matlab.ui.control.Button
        TabGroup_2                      matlab.ui.container.TabGroup
        AntennasTab                     matlab.ui.container.Tab
        LoadAntennaFromFileButton       matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SetAntennatoActiveAntennaButton  matlab.ui.control.Button
        SelectAntennaDropDown           matlab.ui.control.DropDown
        SelectAntennaDropDownLabel      matlab.ui.control.Label
        OperatingFrequencyEditField     matlab.ui.control.NumericEditField
        OperatingFrequencyEditFieldLabel  matlab.ui.control.Label
        CreateFromPresetButton          matlab.ui.control.Button
        DesignCustomAntennaButton       matlab.ui.control.Button
        NameofAntennaEditField          matlab.ui.control.EditField
        NameofAntennaEditFieldLabel     matlab.ui.control.Label
        PresetTypesDropDown             matlab.ui.control.DropDown
        PresetTypesDropDownLabel        matlab.ui.control.Label
        TransmitterSiteTab              matlab.ui.container.Tab
        TransmiterLatitudeEditField     matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        TransmitterLongitudeEditField   matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        TransmitterAddSiteButton        matlab.ui.control.Button
        TransmitterNameofSiteEditField  matlab.ui.control.EditField
        NameofSiteEditFieldLabel        matlab.ui.control.Label
        TransmitterPowerwattsEditField  matlab.ui.control.NumericEditField
        TransmitterPowerwattsEditFieldLabel  matlab.ui.control.Label
        AntennaHeightmetersEditField    matlab.ui.control.NumericEditField
        AntennaHeightmetersEditFieldLabel  matlab.ui.control.Label
        ReceiverSiteTab                 matlab.ui.container.Tab
        MaximumRangemEditField          matlab.ui.control.NumericEditField
        MaximumRangemEditFieldLabel     matlab.ui.control.Label
        ReceiverAddSiteButton           matlab.ui.control.Button
        ReceiverLongitudeEditField      matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        ReceiverLatitudeEditField       matlab.ui.control.NumericEditField
        LatitudeEditField_2Label        matlab.ui.control.Label
        ReceiverNameofSiteEditField     matlab.ui.control.EditField
        NameofSiteEditField_2Label      matlab.ui.control.Label
        ReceiverSentivityEditField      matlab.ui.control.NumericEditField
        ReceiverSentivityEditFieldLabel  matlab.ui.control.Label
        FileUploadTab                   matlab.ui.container.Tab
        PathofFileUploadedEditField     matlab.ui.control.EditField
        PathofFileUploadedEditFieldLabel  matlab.ui.control.Label
        UploadFileButton                matlab.ui.control.Button
        DatasetGenerationTab            matlab.ui.container.Tab
        GenerateDatasetButton           matlab.ui.control.Button
        TransmitterPowerdBmEditField    matlab.ui.control.NumericEditField
        TransmitterPowerdBmEditFieldLabel  matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        NumberofSampleEditField         matlab.ui.control.NumericEditField
        NumberofSampleEditFieldLabel    matlab.ui.control.Label
        GenerateRandomSitesButton       matlab.ui.control.Button
        MapPanel_2                      matlab.ui.container.Panel
        BaseMapDropDown                 matlab.ui.control.DropDown
        BaseMapDropDownLabel            matlab.ui.control.Label
        MapPanel                        matlab.ui.container.Panel
        AdvancedRayTracingPropertiesPanel  matlab.ui.container.Panel
        SurfaceMaterialPanel            matlab.ui.container.Panel
        SurfaceMaterialConductivityEditField_2  matlab.ui.control.NumericEditField
        SurfaceMaterialConductivityEditField_2Label  matlab.ui.control.Label
        SurfaceMaterialPermittivityEditField_2  matlab.ui.control.NumericEditField
        SurfaceMaterialPermittivityEditField_2Label  matlab.ui.control.Label
        SurfaceMaterialDropDown         matlab.ui.control.DropDown
        TerrainMaterialDropDownLabel    matlab.ui.control.Label
        TerrainMaterialPanel            matlab.ui.container.Panel
        TerrainMaterialConductivityEditField  matlab.ui.control.NumericEditField
        SurfaceMaterialConductivityEditFieldLabel  matlab.ui.control.Label
        TerrainMaterialPermittivityEditField  matlab.ui.control.NumericEditField
        SurfaceMaterialPermittivityEditFieldLabel  matlab.ui.control.Label
        TerrainMaterialDropDown         matlab.ui.control.DropDown
        SurfaceMaterialDropDownLabel    matlab.ui.control.Label
        BuildingMaterialPanel           matlab.ui.container.Panel
        BuildingMaterialConductivityEditField  matlab.ui.control.NumericEditField
        BuildingMaterialConductivityEditFieldLabel  matlab.ui.control.Label
        BuildingMaterialPermittivityEditField  matlab.ui.control.NumericEditField
        BuildingMaterialPermittivityEditFieldLabel  matlab.ui.control.Label
        BuildingMaterialsDropDown       matlab.ui.control.DropDown
        BuildingMaterialsDropDownLabel  matlab.ui.control.Label
        RayTracingPanel                 matlab.ui.container.Panel
        MaxRelativePathLossEditField    matlab.ui.control.NumericEditField
        MaxRelativePathLossEditFieldLabel  matlab.ui.control.Label
        MaxAbsolutePathLossEditField    matlab.ui.control.NumericEditField
        MaxAbsolutePathLossEditFieldLabel  matlab.ui.control.Label
        MaxDiffractionsDropDown         matlab.ui.control.DropDown
        MaxDiffractionsDropDownLabel    matlab.ui.control.Label
        MaxReflectionsEditField         matlab.ui.control.NumericEditField
        MaxReflectionsEditFieldLabel    matlab.ui.control.Label
        AngularSeparationDropDown       matlab.ui.control.DropDown
        AngularSeparationDropDownLabel  matlab.ui.control.Label
        EnvironmentalPropertiesPanel    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TargetRegionTab                 matlab.ui.container.Tab
        SouthWestLongitudeEditField     matlab.ui.control.NumericEditField
        SouthWestLongitudeEditField_2Label  matlab.ui.control.Label
        NorthEastLongitudeEditField     matlab.ui.control.NumericEditField
        NorthEastLongitudeEditFieldLabel  matlab.ui.control.Label
        PlotTargetRegionButton          matlab.ui.control.Button
        SouthWestLatitudeEditField      matlab.ui.control.NumericEditField
        SouthWestLongitudeLabel         matlab.ui.control.Label
        NorthEastLatitudeEditField      matlab.ui.control.NumericEditField
        NorthEastLatitudeEditFieldLabel  matlab.ui.control.Label
        PropagationModelTab             matlab.ui.container.Tab
        AdvancedPropertiesButton        matlab.ui.control.Button
        PropagationModelDropDown        matlab.ui.control.DropDown
        PropagationModelDropDownLabel   matlab.ui.control.Label
    end


    properties (Access = private)
        Latitudes = []% Description
        Longitudes = []% Description
        transmitterLat = []
        receiverLat = []
        transmitterLon = []
        receiverLon = []
        transmitters = []
        receivers = []
        siteType = []
        coordinateSytem = []
        transmitterAntenna = []
        transmitterAntennaAngle = []
        transmitterAntennaHeight = []
        transmitterAntennaPosition = []
        transmitterSystemLoss = []
        transmitterFrequency = []
        transmitterPower = []
        receiverAntenna = []
        receiverAntennaAngle = []
        receiverAntennaHeight = []
        receiverAntennaPosition = []
        receiverSystemLoss = []
        receiverSenstivity = []
        geoPlotAxes
        txa
        rxsa
        randLatitudes
        randLongitudes
        centroidLat
        centroidLon
        siteN
        figs = []
        propRainRate
        propRainTilt
        
        propGasAirTemp
        propGasDryAirPressure
        propGasWaterVapourDensity
        
        propFogAirTemp
        propFogLiquidWaterDensity
        
        propCloseInReferenceDistance
        propCloseInPathLossExponent
        propCloseInStandardDeviation
        propCloseInNumOfDataPoints
        
        propLongleyAntennaPolarization
        propLongleyGroundConductivity
        propLongleyGroundPermitivity
        propLongleyAtmosphericRefractivity
        propLongleyTimeVariabilityTolerance
        propLongleySituationVariabilityTolerance
        
        propRaytracingSurfaceMaterialConductivity
        propRaytracingSurfaceMaterialPermitivity
        propRaytracingSurfaceMaterial
        propRaytracingTerrainMaterialConductivity
        propRaytracingTerrainMaterialPermitivity
        propRaytracingTerrainMaterial
        propRaytracingBuildingMaterialConductivity
        propRaytracingBuildingMaterialPermitivity
        propRaytracingBuildingMaterial
        propRaytracingMaxRelativePathLoss
        propRaytracingMaxAbsolutePathLoss
        propRaytracingMaxdiffraction
        propRaytracingMaxReflection
        propRaytracingAngularSeparation
        
        mobilityFilePath % Description
        mobilityFileName
        
        %Plot region
        Region  %Stores cordinates that define the region

        %Antenna Tab
        ActiveAntenna % Set by choosing antenna in antenna tab
        AntennaList = Antennas() %limit ten, stores Antenna info

        %Site Creation
        ActiveLocation
        ActiveTransmitterSite
        ActiveRecieverSite
        ActiveTransmitter
        ActiveReciever
        TransmitterSites = Transmitters()
        RecieverSites = Recievers()
        CoverageData

        %General
        PropagationModel = 'freespace'

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransmitterAddSiteButton
        function TransmitterAddSiteButtonPushed(app, event)
            %determine the location of the transmitter from UI
            app.ActiveLocation = Location(app.TransmiterLatitudeEditField.Value, ...
                app.TransmitterLongitudeEditField.Value);            
            app.ActiveLocation.Name = app.TransmitterNameofSiteEditField.Value;
            
            %attach the active antena from antenna tab
            %add height, tilt and power info to antenna object
            %update antenna frequecy
            app.ActiveTransmitter = app.ActiveAntenna;
            app.ActiveTransmitter.Power = app.TransmitterPowerwattsEditField.Value;
            app.ActiveTransmitter.Height = app.AntennaHeightmetersEditField.Value;

           % app.ActiveAntenna.OpFreq = str2double([app.TransmitterFrequencye9HzEditField.Value 'e9']);
           % app.transmitterAntennaAngle = [app.transmitterAntennaAngle 0];
           % app.transmitterAntennaHeight = [app.transmitterAntennaHeight app.AntennaHeightmetersEditField.Value];
           % app.transmitterAntennaPosition = [app.transmitterAntennaPosition 0];
           % app.transmitterSystemLoss = [app.transmitterSystemLoss 0];
           % app.transmitterFrequency = [app.transmitterFrequency app.TransmitterFrequencye9HzEditField.Value];
            
            %create site and set it to the active site
            app.ActiveTransmitterSite = app.ActiveTransmitter.Generate_T_Site(app.ActiveLocation.Name, ...
                app.ActiveLocation);
            %add site to the transmitters sites list
            app.TransmitterSites.list{end+1} = app.ActiveTransmitterSite;
            app.TransmitterSitesListBox.Items{end+1} = app.ActiveTransmitterSite.Name;

            %             app.Latitudes = [app.Latitudes (app.TransmiterLatitudeEditField.Value)];
            %             app.Longitudes = [app.Longitudes (app.TransmitterLongitudeEditField.Value)];
            %             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            
            geoplot(app.geoPlotAxes, app.ActiveLocation.Latitude, ...
                app.ActiveLocation.Longitude, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20);
            hold(app.geoPlotAxes, 'on');
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value);
            
            
        end

        % Button pushed function: ReceiverAddSiteButton
        function ReceiverAddSiteButtonPushed(app, event)
            %set site location and name
            app.ActiveLocation = Location(app.ReceiverLatitudeEditField.Value, ...
                app.ReceiverLongitudeEditField.Value);
            app.ActiveLocation.Name = app.ReceiverNameofSiteEditField.Value;
            
            %add additional information for antenna to be a reciever
            app.ActiveAntenna.Sensitivity = app.ReceiverSentivityEditField.Value;
            app.ActiveAntenna.MaxRange = app.MaximumRangemEditField.Value;
            
            %Create site
            app.ActiveRecieverSite = app.ActiveAntenna.Generate_R_Site(app.ActiveLocation.Name, app.ActiveLocation);
            app.RecieverSites.list{end+1} = app.ActiveRecieverSite;
            
            %add site to collection
            app.RecieverSitesListBox.Items{end+1} = app.ActiveRecieverSite.Name;

            %             app.Latitudes = [app.Latitudes (app.ReceiverLatitudeEditField.Value)];
            %             app.Longitudes = [app.Longitudes (app.ReceiverLongitudeEditField.Value)];
            %             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            
            %plot site
            geoplot(app.geoPlotAxes, app.ActiveLocation.Latitude, ...
                app.ActiveLocation.Longitude, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: PlotIdealcoverageAreaButton
        function PlotIdealcoverageAreaButtonPushed(app, event)
            show(app.ActiveTransmitterSite);
            pattern(app.ActiveTransmitterSite, app.ActiveTransmitter.OpFreq);
            coverage(app.ActiveTransmitterSite, 'freespace');

            show(app.ActiveRecieverSite);
            link(app.ActiveRecieverSite, app.ActiveTransmitterSite,"freespace");

        end

        % Button pushed function: PlotDirectivityPatternButton
        function PlotDirectivityPatternButtonPushed(app, event)
            

            % Show directivity pattern
            patternAzimuth(app.ActiveAntenna.Sim, app.ActiveAntenna.OpFreq);

            %{ 
            % Get the content of the figure as an image
            figImage = getframe(f);
            imageData = figImage.cdata;

         
            Display the image in the UIAxes
            %             imshow(imageData, 'Parent', app.DirectivityPlot);
            app.txa(1).Antenna = yagiAnt;

            % Point main beam toward Boston, MA by assigning azimuth angle between
            % transmitter location and Boston receiver location
            app.txa(1).AntennaAngle = angle(app.txa(1), app.rxsa(1));

            % Define signal strengths from sensitivity to -60 dB
            sigStrengths = app.ReceiverSentivityEditField.Value:5:-60;
            sc = [0 0.3 0];

            % Update coverage map
            coverage(app.txa,"rain","SignalStrengths",sigStrengths)

            link(app.rxsa,app.txa,"rain","SuccessColor",sc) 
            %}
        end

        % Button pushed function: PlotTargetRegionButton
        function PlotTargetRegionButtonPushed(app, event)
           % app.TransmitterSitesListBox.Items = {};
           % app.ReceiverSitesListBox.Items = {};
           % app.transmitters = [];
           % app.transmitterLat = [];
           % app.transmitterLon = [];

           % app.receivers = [];
           % app.receiverLat = [];
           % app.receiverLon = [];
            % Define the bounding box coordinates
            

           % ne_latitude = app.SouthWestLatitudeEditField.Value;  % North-east latitude
           % sw_longitude = app.NorthEastLongitudeEditField.Value;  % South-west longitude
           % nw_latitude = app.NorthEastLatitudeEditField.Value;  % North-west latitude
           % se_longitude = app.SouthWestLongitudeEditField.Value;  % South-east longitude

            %get region bounds from UI
            NorthEastCorner = Location(app.NorthEastLatitudeEditField.Value, ...
                app.NorthEastLongitudeEditField.Value);
            SouthWestCorner = Location(app.SouthWestLatitudeEditField.Value, ...
                app.SouthWestLongitudeEditField.Value);
            app.Region = Region(NorthEastCorner, SouthWestCorner);

            % Plot the bounding box
            geoAxes = geoaxes(app.MapPanel);
            app.geoPlotAxes = geoAxes;
            geoplot(app.geoPlotAxes, [app.Region.North, app.Region.North, app.Region.South, app.Region.South, app.Region.North], ...
                [app.Region.West, app.Region.East, app.Region.East, app.Region.West, app.Region.West], 'g-.', 'LineWidth', 2);
            hold(app.geoPlotAxes, 'on')
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: UploadFileButton
        function UploadFileButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat', 'Select .mat file');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled the file selection
                return;
            end

            app.PathofFileUploadedEditField.Value = [filepath filename];
            % Load the .mat file
            load(fullfile(filepath, filename));
            SiteType = data.SiteType;
            disp(SiteType)

            SiteName = data.Name;
            transmitterSiteNames = SiteName(SiteType == 0);
            receiverSiteNames = SiteName(SiteType == 1);
            app.transmitters = [app.transmitters, transmitterSiteNames];
            app.receivers = [app.receivers, receiverSiteNames];

            SiteLatitude = data.Latitude;
            transmitterSiteLatitude = SiteLatitude(SiteType == 0);
            receiverSiteLatitude = SiteLatitude(SiteType == 1);
            app.transmitterLat = [app.transmitterLat, transmitterSiteLatitude];
            app.receiverLat = [app.receiverLat, receiverSiteLatitude];

            SiteLongitude = data.Longitude;
            transmitterSiteLongitude = SiteLongitude(SiteType == 0);
            receiverSiteLongitude = SiteLongitude(SiteType == 1);
            app.transmitterLon = [app.transmitterLon, transmitterSiteLongitude];
            app.receiverLon = [app.receiverLon, receiverSiteLongitude];

            TransmitterAntenna = data.TransmitterAntenna;
            ReceiverAntenna = data.ReceiverAntenna;
            transmitterSiteAntenna = TransmitterAntenna(SiteType == 0);
            receiverSiteAntenna = ReceiverAntenna(SiteType == 1);
            app.transmitterAntenna = [app.transmitterAntenna, transmitterSiteAntenna];
            app.receiverAntenna = [app.receiverAntenna, receiverSiteAntenna];

            TransmitterAntennaAngle = data.TransmitterAntennaAngle;
            ReceiverAntennaAngle = data.ReceiverAntennaAngle;
            transmitterSiteAntennaAngle = TransmitterAntennaAngle(SiteType == 0);
            receiverSiteAntennaAngle = ReceiverAntennaAngle(SiteType == 1);
            app.transmitterAntennaAngle = [app.transmitterAntennaAngle, transmitterSiteAntennaAngle];
            app.receiverAntennaAngle = [app.receiverAntennaAngle, receiverSiteAntennaAngle];

            TransmitterAntennaHeight = data.TransmitterAntennaHeight;
            ReceiverAntennaHeight = data.ReceiverAntennaHeight;
            transmitterSiteAntennaHeight = TransmitterAntennaHeight(SiteType == 0);
            receiverSiteAntennaHeight = ReceiverAntennaHeight(SiteType == 1);
            app.transmitterAntennaHeight = [app.transmitterAntennaHeight, transmitterSiteAntennaHeight];
            app.receiverAntennaHeight = [app.receiverAntennaHeight, receiverSiteAntennaHeight];

            TransmitterAntennaPosition = data.TransmitterAntennaPosition;
            ReceiverAntennaPosition = data.ReceiverAntennaPosition;
            transmitterSiteAntennaPosition = TransmitterAntennaPosition(SiteType == 0);
            receiverSiteAntennaPosition = ReceiverAntennaPosition(SiteType == 1);
            app.transmitterAntennaPosition = [app.transmitterAntennaPosition, transmitterSiteAntennaPosition];
            app.receiverAntennaPosition = [app.receiverAntennaPosition, receiverSiteAntennaPosition];

            TransmitterSystemLoss = data.TransmitterSystemLoss;
            ReceiverSystemLoss = data.ReceiverSystemLoss;
            transmitterSiteSystemLoss = TransmitterSystemLoss(SiteType == 0);
            receiverSiteSystemLoss = ReceiverSystemLoss(SiteType == 1);
            app.transmitterSystemLoss = [app.transmitterSystemLoss, transmitterSiteSystemLoss];
            app.receiverSystemLoss = [app.receiverSystemLoss, receiverSiteSystemLoss];

            TransmitterFrequency = data.TransmitterFrequency;
            transmitterSiteFrequency = TransmitterFrequency(SiteType == 0);
            app.transmitterFrequency = [app.transmitterFrequency, transmitterSiteFrequency];

            TransmitterPower = data.TransmitterPower;
            transmitterSitePower = TransmitterPower(SiteType == 0);
            app.transmitterPower = [app.transmitterPower, transmitterSitePower];

            ReceiverSensitivity = data.ReceiverSensitivity;
            receiverSiteSentivity = ReceiverSensitivity(SiteType == 1);
            app.receiverSenstivity = [app.receiverSenstivity, receiverSiteSentivity];
            disp(transmitterSiteNames)
            for i = 1 : length(transmitterSiteNames)
                app.TransmitterSitesListBox.Items{end+1} = transmitterSiteNames{i};
            end

            for i = 1 : length(receiverSiteNames)
                app.ReceiverSitesListBox.Items{end+1} = receiverSiteNames{i};
            end


            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)


        end

        % Button pushed function: GenerateRandomSitesButton
        function GenerateRandomSitesButtonPushed(app, event)
            % Define the bounding box coordinates

            n = app.nEditField.Value;
            m = app.mEditField.Value;
            app.CoverageData = {};

            for row = 1:n
                for col = 1:m
                    lat = ((app.Region.South - app.Region.North) / n) + app.Region.North;
                    long = ((app.Region.West - app.Region.East) / m) + app.Region.East;
                    app.ActiveLocation = Location(lat, long);
                    name = [num2str(row), '-', num2str(col)];
                    app.CoverageData{end + 1} = app.ActiveAntenna.Generate_T_Site(name,app.ActiveLocation);
                end
            end
            disp("Ready")
         
        end

        % Callback function
        function HeatMapbasedonGridButtonPushed(app, event)
            n = app.nEditField.Value;
            m = app.mEditField.Value;
            ntr = str2double(app.NumberofTransmitterinaRegionDropDown.Value);
            % Set transmitter parameters (adjust according to your specific scenario)
            num = app.TransmitterFrequencye9HzEditField_2.Value;
            str = 'e9';
            fq1 = str2double([num2str(num) str]);
            transmitterFreq = fq1; % Transmitter frequency in Hz
            transmitterPow = app.TransmitterPowerdBmEditField.Value; % Transmitter power in dBm
            % Initialize the received power matrix
            totalCentroids = numel(app.centroidLat);
            receivedPowerMatrix = zeros(totalCentroids, 1);
            if ntr == 1
                siteNum = app.RandomSitesDropDown.Value;
                transLat  = app.randLatitudes(siteNum);
                transLon = app.randLongitudes(siteNum);
                receivedPowerMatrix = zeros(totalCentroids, 1);

                for i = 1:totalCentroids
                    tx = txsite('Latitude',transLat,'Longitude',transLon, ...
                        'TransmitterFrequency', transmitterFreq, "TransmitterPower", transmitterPow);
                    yagiAnt = design(yagiUda, transmitterFreq);

                    yagiAnt.Tilt = app.TiltAngleEditField_2.Value;
                    yagiAnt.TiltAxis = app.TiltAxisDropDown_2.Value;

                    tx.Antenna = yagiAnt;

                    rx = rxsite('Latitude',app.centroidLat(i),'Longitude',app.centroidLon(i));

                    % Calculate the distance from the transmitter to each centroid coordinate
                    %                 distance = haversine(transLat, transLon, app.centroidLat(i), app.centroidLon(i));

                    pm = propagationModel("raytracing");
                    ss = sigstrength(rx,tx,pm);
                    % Calculate the received power at the centroid using the Friis transmission equation
                    %                 receivedPower = transmitterPow - pathloss(pm,rx,tx);

                    % Store the received power in the matrix
                    receivedPowerMatrix(i) = ss;

                end

            elseif ntr == 2
                siteNum1 = app.RandomTransmiterSite1DropDown.Value;
                siteNum2 = app.RandomTransmiterSite2DropDown.Value;
                receivedPowerMatrix = zeros(totalCentroids, 1);

                for i = 1:totalCentroids
                    tx = txsite('Latitude',[app.randLatitudes(siteNum1) app.randLatitudes(siteNum2)],'Longitude',[app.randLongitudes(siteNum1) app.randLongitudes(siteNum2)], ...
                        'TransmitterFrequency', transmitterFreq, "TransmitterPower", transmitterPow);

                    rx = rxsite('Latitude',app.centroidLat(i),'Longitude',app.centroidLon(i));
                    % Calculate the distance from the transmitter to each centroid coordinate
                    %                 distance = haversine(transLat, transLon, app.centroidLat(i), app.centroidLon(i));
                    yagiAnt = design(yagiUda, transmitterFreq);

                    yagiAnt.Tilt = tiltAngle;
                    yagiAnt.TiltAxis = tiltAxis;

                    tx(1).Antenna = yagiAnt;
                    tx(2).Antenna = yagiAnt;

                    pm = propagationModel("raytracing");
                    ss = sigstrength(rx,tx,pm);

                    % Calculate the combined signal strength (power sum)
                    % This formula converts the signal strengths into
                    % linear scales and then add them together and converts
                    % back to dB from linear scale
                    % For actual combined signal strength we need to
                    % consider the factors distance, path loss, fading,
                    % interference, and other relevant paramenters (mainly wireless system being adopted).
                    combinedSignalStrength = 10*log10(sum(10.^(ss./10)));

                    % Store the received power in the matrix
                    receivedPowerMatrix(i) = combinedSignalStrength;
                end
            end

            % Reshape the received power values back into the n-by-m matrix
            receivedPowerMatrix = reshape(receivedPowerMatrix, n, m);
            disp(receivedPowerMatrix)

            % Create a heatmap of the received power matrix
            figure;
            imagesc(receivedPowerMatrix);

            % Customize the colormap
            colormap jet; % Choose a colormap (e.g., jet, parula, colormap name)
            colorbar;     % Display a colorbar

            %             elapsed_time = toc;

            %             app.TimeTakenforpreparingHeatMapEditField.Value = elapsed_time;

        end

        % Callback function
        function SaveFilesButtonPushed(app, event)
            app.SavingStatusEditField.Value = "Saving Started";
            n = app.nEditField.Value;
            m = app.mEditField.Value;
            num_points = n*m;
            siteNames = 1:num_points;

            ntr = str2double(app.NumberofTransmitterinaRegionDropDown.Value);
            %% NTR = 1
            if ntr == 1

                index_table = table(app.siteN', app.randLatitudes', app.randLongitudes', 'VariableNames', {'SiteName', 'Latitude', 'Longitude'});

                for i = 1 : num_points
                    %                     siteNum = app.RandomSitesDropDown.Value;
                    transLat  = app.randLatitudes(i);
                    transLon = app.randLongitudes(i);

                    % Set transmitter parameters (adjust according to your specific scenario)
                    transmitterFreq = 1.9e9; % Transmitter frequency in Hz
                    transmitterPow = 10; % Transmitter power in dBm

                    tx = txsite('Latitude',transLat,'Longitude',transLon, ...
                        'TransmitterFrequency', transmitterFreq, "TransmitterPower", transmitterPow);
                    yagiAnt = design(yagiUda, transmitterFreq);

                    yagiAnt.Tilt = tiltAngle;
                    yagiAnt.TiltAxis = tiltAxis;

                    tx.Antenna = yagiAnt;

                    totalCentroids = numel(app.centroidLat);

                    % Initialize the received power matrix
                    receivedPowerMatrix = zeros(totalCentroids, 1);
                    for j = 1 : totalCentroids
                        rx = rxsite('Latitude',app.centroidLat(j),'Longitude',app.centroidLon(j));

                        % Calculate the distance from the transmitter to each centroid coordinate
                        %                 distance = haversine(transLat, transLon, app.centroidLat(i), app.centroidLon(i));

                        pm = propagationModel("raytracing", "Method", "sbr", "BuildingsMaterial","perfect-reflector");
                        ss = sigstrength(rx,tx,pm);
                        % Calculate the received power at the centroid using the Friis transmission equation
                        %                 receivedPower = transmitterPow - pathloss(pm,rx,tx);

                        % Store the received power in the matrix
                        receivedPowerMatrix(j) = ss;
                    end

                    % Reshape the received power values back into the n-by-m matrix
                    receivedPowerMatrix = reshape(receivedPowerMatrix, n, m);

                    % Create a heatmap of the received power matrix
                    fig = figure('Visible', 'off');
                    imagesc(receivedPowerMatrix);
                    % Customize the colormap
                    colormap jet; % Choose a colormap (e.g., jet, parula, colormap name)
                    colorbar;     % Display a colorbar
                    title('Received Power Heatmap');

                    app.figs = [app.figs, fig];
                    figFilename = sprintf('figure_%d.png', i);
                    pod = string(app.PathofDirectoryEditField.Value);
                    pathAddress = strcat(pod,"\");
                    fullFilePath = fullfile(pathAddress, figFilename);
                    saveas(fig, fullFilePath);


                end

                for i = 1 : num_points
                    tx = txsite("Name",string(i),"Latitude",app.randLatitudes(i),"Longitude",app.randLongitudes(i));
                    yagiAnt = design(yagiUda, transmitterFreq);

                    yagiAnt.Tilt = tiltAngle;
                    yagiAnt.TiltAxis = tiltAxis;

                    tx.Antenna = yagiAnt;
                    pd = coverage(tx,'PropagationModel','raytracing',"SignalStrengths",-90,5:-60);
                    pd_table = pd.Data;
                    filename = strcat(string(i),".csv");
                    pod = string(app.PathofDirectoryEditField.Value);
                    pathAddress = strcat(pod,"\");
                    filepath = strcat(pathAddress, filename);
                    % Save the table to a CSV file
                    writetable(pd_table, filepath);

                end

                filename = strcat("index_table",".csv");
                filepath = strcat(pathAddress, filename);
                writetable(index_table, filepath);
                app.SavingStatusEditField.Value = "Saving Successful";
                %% NTR = 2
            elseif ntr == 2
                site1TransLat = [];
                site2TransLat = [];
                site1TransLon = [];
                site2TransLon = [];
                sites2Trans = [];
                for k = 1 : length(app.randLatitudes)
                    for j = 1 : length(app.randLatitudes)
                        siteNum1 = k;
                        siteNum2 = j;
                        site1TransLat = [site1TransLat app.randLatitudes(k)];
                        site1TransLon = [site1TransLon app.randLongitudes(k)];
                        site2TransLat = [site2TransLat app.randLatitudes(j)];
                        site2TransLon = [site2TransLon app.randLongitudes(j)];
                        a = string(app.siteN(k)+ "-");
                        sites2Trans = [sites2Trans strcat(string(a),(app.siteN(j)))];
                        receivedPowerMatrix = zeros(numel(app.centroidLat), 1);
                        % Set transmitter parameters (adjust according to your specific scenario)
                        num = app.TransmitterFrequencye9HzEditField_2.Value;
                        str = 'e9';
                        fq1 = str2double([num2str(num) str]);
                        transmitterFreq = fq1; % Transmitter frequency in Hz
                        transmitterPow = app.TransmitterPowerdBmEditField.Value;

                        for i = 1:numel(app.centroidLat)
                            tx = txsite('Latitude',[app.randLatitudes(siteNum1) app.randLatitudes(siteNum2)],'Longitude',[app.randLongitudes(siteNum1) app.randLongitudes(siteNum2)], ...
                                'TransmitterFrequency', transmitterFreq, "TransmitterPower", transmitterPow);

                            rx = rxsite('Latitude',app.centroidLat(i),'Longitude',app.centroidLon(i));

                            yagiAnt = design(yagiUda, transmitterFreq);

                            yagiAnt.Tilt = tiltAngle;
                            yagiAnt.TiltAxis = tiltAxis;

                            tx(1).Antenna = yagiAnt;
                            tx(2).Antenna = yagiAnt;

                            pm = propagationModel("raytracing", "Method", "sbr", "BuildingsMaterial","perfect-reflector");
                            ss = sigstrength(rx,tx,pm);
                            disp("The signal strength is "+ss)
                            %                             y = isinf(ss(1));
                            %                             if y == 1
                            %                                 ss(1) = (-1000);
                            %                             end
                            %                             y = isinf(ss(2));
                            %                             if y == 1
                            %                                 ss(2) = (-1000);
                            %                             end

                            % Calculate the combined signal strength (power sum)
                            combinedSignalStrength = 10*log10(sum(10.^(ss./10)));

                            % Store the received power in the matrix
                            receivedPowerMatrix(i) = combinedSignalStrength;

                        end

                        % Reshape the received power values back into the n-by-m matrix
                        receivedPowerMatrix = reshape(receivedPowerMatrix, n, m);

                        % Create a heatmap of the received power matrix
                        fig = figure('Visible', 'off');
                        imagesc(receivedPowerMatrix);
                        % Customize the colormap
                        colormap jet; % Choose a colormap (e.g., jet, parula, colormap name)
                        colorbar;     % Display a colorbar
                        title('Received Power Heatmap');

                        app.figs = [app.figs, fig];
                        figFilename = sprintf('figure_%d_%d.png', k, j);
                        pod = string(app.PathofDirectoryEditField.Value);
                        pathAddress = strcat(pod,"\");
                        fullFilePath = fullfile(pathAddress, figFilename);
                        saveas(fig, fullFilePath);

                        disp("done")
                    end
                end

                index_table = table(sites2Trans', site1TransLat', site1TransLon', site2TransLat', site2TransLon', 'VariableNames', {'SiteName', 'Transmitter Site 1 Latitude', 'Transmitter Site 1 Longitude', 'Transmitter Site 2 Latitude', 'Transmitter Site 2 Longitude'});

                for k = 1 : length(app.randLatitudes)
                    for j = 1 : length(app.randLatitudes)
                        siteNum1 = k;
                        siteNum2 = j;

                        num = app.TransmitterFrequencye9HzEditField_2.Value;
                        str = 'e9';
                        fq1 = str2double([num2str(num) str]);
                        transmitterFreq = fq1; % Transmitter frequency in Hz
                        transmitterPow = app.TransmitterPowerdBmEditField.Value;
                        tx = txsite('Latitude',[app.randLatitudes(siteNum1) app.randLatitudes(siteNum2)],'Longitude',[app.randLongitudes(siteNum1) app.randLongitudes(siteNum2)], ...
                            'TransmitterFrequency', transmitterFreq, "TransmitterPower", transmitterPow);
                        yagiAnt = design(yagiUda, transmitterFreq);

                        yagiAnt.Tilt = tiltAngle;
                        yagiAnt.TiltAxis = tiltAxis;

                        tx(1).Antenna = yagiAnt;
                        tx(2).Antenna = yagiAnt;
                        pd = coverage(tx,'PropagationModel','raytracing',"SignalStrengths",-90:5:-60);
                        pd_table = pd.Data;
                        siteName = strcat(string(k),string(j));
                        filename = strcat(siteName,".csv");
                        pod = string(app.PathofDirectoryEditField.Value);
                        pathAddress = strcat(pod,"\");
                        filepath = strcat(pathAddress, filename);
                        % Save the table to a CSV file
                        writetable(pd_table, filepath);

                        %                         for i = 1:numel(app.centroidLat)
                        %                             tx = txsite('Latitude',[app.randLatitudes(siteNum1) app.randLatitudes(siteNum2)],'Longitude',[app.randLongitudes(siteNum1) app.randLongitudes(siteNum2)], ...
                        %                                 'TransmitterFrequency', transmitterFreq, "TransmitterPower", transmitterPow);
                        %                             pd = coverage(tx,'PropagationModel','raytracing',"SignalStrengths",-500:0);
                        %                             pd_table = pd.Data;
                        %                             filename = strcat(string(i),".csv");
                        %                             pod = string(app.PathofDirectoryEditField.Value);
                        %                             pathAddress = strcat(pod,"\");
                        %                             filepath = strcat(pathAddress, filename);
                        %                             % Save the table to a CSV file
                        %                             writetable(pd_table, filepath);
                        %
                        %                         end

                    end
                end
                filename = strcat("index_table",".csv");
                filepath = strcat(pathAddress, filename);
                writetable(index_table, filepath);
                app.SavingStatusEditField.Value = "Saving Successful";


            end
            %%


        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            app.NumberofSampleEditField.Value = (value*app.mEditField.Value);
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.NumberofSampleEditField.Value = (value*app.nEditField.Value);
        end

        % Callback function
        function DirectivityPatternSwitchValueChanged(app, event)
            value = app.DirectivityPatternSwitch.Value;
            if value == "On"
                app.TiltAxisDropDown_2.Visible = "on";
                app.TiltAxisDropDown_2Label.Visible = 'on';
                app.TiltAngleEditField_2.Visible = 'on';
                app.TiltAngleEditField_2Label.Visible = 'on';
            elseif value == "Off"
                app.TiltAxisDropDown_2.Visible = "off";
                app.TiltAxisDropDown_2Label.Visible = 'off';
                app.TiltAngleEditField_2.Visible = 'off';
                app.TiltAngleEditField_2Label.Visible = 'off';
            end
        end

        % Callback function
        function MobilityButtonPushed(app, event)
            % Create the popup window
            popup = uifigure('Position', [100 100 510 200], 'Name', "Advanced Ray Tracing Properties");
            popup.Scrollable = 'on';

            label11 = uilabel(popup, 'Text', 'Transmitter Latitude:');
            label11.Position = [30 100 160 20];
            editField11 = uieditfield(popup, 'numeric', 'Value', 33.2098);
            editField11.Position = [170 100 70 20];

            label12 = uilabel(popup, 'Text', 'Transmitter Longitude:');
            label12.Position = [250 100 160 20];
            editField12 = uieditfield(popup, 'numeric', 'Value', -97.1449);
            editField12.Position = [430 100 70 20];
            
            % Create the submit button
            uploadButton = uibutton(popup, 'push');
            uploadButton.Text = 'Upload File';
            uploadButton.Position = [230 50 100 20];
            uploadButton.ButtonPushedFcn = @(btn,event) uploadFileCallback(app, popup);

            % Add a uilabel to display the file path
%             app.mobilityFilePathLabel = uilabel(popupFig, 'Text', '', ...
%                 'Position', [50, 50, 200, 30]);


            % Callback function for the "Upload File" button in the pop-up
            function uploadFileCallback(app, popup)
                % Open a file dialog for the user to select the file
                [filename, filepath] = uigetfile('*.mat', 'Select the .mat File');

                % Check if the user selected a file
                if ischar(filename) && ischar(filepath)
                    % File selected, update the file path label
                    app.mobilityFilePathLabel.Text = fullfile(filepath, filename);
                    app.mobilityFilePath = filepath;
                    app.mobilityFileName = filename;
                end

                load(fullfile(app.mobiltyFilePath, app.mobilityFileName));
                t = data.timeStamp;
                rLati = data.Latitude;
                rLongi = data.Longitude;

                transLat = editField11;
                transLon = editField12;
                num = 5.9;
                str = 'e9';
                fq1 = str2double([num2str(num) str]);
                transmitterFreq = fq1; % Transmitter frequency in Hz
                transmitterPow = 23; % Transmitter power in dBm
                transpow = zeros(len(t));

                tx = txsite('Latitude',transLat,'Longitude',transLon, ...
                    'TransmitterFrequency', transmitterFreq, "TransmitterPower", transmitterPow);
                for i = 1 : len(t)
                    rx = rxsite('Latitude',rLati(i),'Longitude',rLongi(i));
                    pm = propagationModel("raytracing");
                    ss = sigstrength(rx,tx,pm);
                    transpow(i) = ss;
                end
            end
%             app.AdvancedRayTracingPropertiesPanel.Visible = 'on';


        end

        % Callback function
        function AdvancedAntennaPropertiesButtonPushed(app, event)
            popup = uifigure('Position', [100 100 420 300], 'Name', "Advanced Antenna Properties");
            popup.Scrollable = 'on';

            label11 = uilabel(popup, 'Text', 'Azimuth Angle Min:');
            label11.Position = [30 250 160 20];
            editField11 = uieditfield(popup, 'numeric');
            editField11.Position = [170 250 40 20];

            label12 = uilabel(popup, 'Text', 'Azimuth Angle Max:');
            label12.Position = [220 250 160 20];
            editField12 = uieditfield(popup, 'numeric');
            editField12.Position = [370 250 40 20];

            label21 = uilabel(popup, 'Text', 'Elevation Angle Min:');
            label21.Position = [30 220 160 20];
            editField21 = uieditfield(popup, 'numeric');
            editField21.Position = [170 220 40 20];

            label22 = uilabel(popup, 'Text', 'Elevation Angle Max:');
            label22.Position = [220 220 160 20];
            editField22 = uieditfield(popup, 'numeric');
            editField22.Position = [370 220 40 20];

            label31 = uilabel(popup, 'Text', 'Max Side-Lobe Attenuation:');
            label31.Position = [30 180 160 20];
            editField31 = uieditfield(popup, 'numeric');
            editField31.Position = [170 180 40 20];

            label41 = uilabel(popup, 'Text', 'Tilt (deg):');
            label41.Position = [30 150 160 20];
            editField41 = uieditfield(popup, 'numeric');
            editField41.Position = [170 150 40 20];


            % Create the submit button
            submitButton = uibutton(popup, 'push');
            submitButton.Text = 'Submit';
            submitButton.Position = [180 50 50 30];
            submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed( ...
                editField11.Value, ...
                editField12.Value, ...
                editField21.Value, ...
                editField22.Value, ...
                editField31.Value, ...
                editField41.Value);

            % Function to handle the submit button press
            function submitButtonPushed(input1, input2, input3, input4, input5, input6)
                % Perform any desired actions with the input values
                azvec = -(input1):input2; % Azimuth angles (deg)
                elvec = -(input3):input4; % Elevation angles (deg)
                SLA = input5; % Maximum side-lobe level attenuation (dB)
                tilt = input6; % Tilt angle (deg)
                az3dB = 65; % 3 dB beamwidth in azimuth (deg)
                el3dB = 65; % 3 dB beamwidth in elevation (deg)
                lambda = physconst("lightspeed")/1.9e9; % Wavelength (m)

                [az,el] = meshgrid(azvec,elvec);
                azMagPattern = -min(12*(az/az3dB).^2,SLA);
                elMagPattern = -min(12*((el-tilt)/el3dB).^2,SLA);
                combinedMagPattern = -min(-(azMagPattern + elMagPattern),SLA); % Relative antenna gain (dB)
                antennaElement = phased.CustomAntennaElement("MagnitudePattern",combinedMagPattern);

                ntr = str2double(app.NumberofTransmitterinaRegionDropDown.Value);
                n = app.nEditField.Value;
                m = app.mEditField.Value;
                if app.DirectivityPatternSwitch.Value == "On"
                    tiltAngle = app.TiltAngleEditField_2.Value;
                    tiltAxis = app.TiltAxisDropDown_2.Value;
                end

                num = app.TransmitterFrequencye9HzEditField_2.Value;
                str = 'e9';
                fq1 = str2double([num2str(num) str]);
                transmitterFreq = fq1; % Transmitter frequency in Hz


                num_points = n*m;
                viewer = siteviewer("Buildings","map.osm");
                siteNum = app.RandomSitesDropDown.Value;
                tx = txsite("Name",string(siteNum),"Latitude",app.randLatitudes(siteNum),"Longitude",app.randLongitudes(siteNum), "TransmitterFrequency", 1.9e9);
                tx.Antenna = phased.URA("Size",[8 8], ...
                    "Element",antennaElement, ...
                    "ElementSpacing",[lambda/2 lambda/2]);
                tx.AntennaAngle = -90;

                clearMap(viewer)
                pattern(tx,"Transparency",0.6)
%                 coverage(tx,'PropagationModel','raytracing',"SignalStrengths",-500:0)


                % Close the popup window
                close(popup);
            end

        end

        % Button pushed function: AdvancedPropertiesButton
        function AdvancedPropertiesButtonPushed(app, event)
            
            
            switch app.PropagationModelDropDown.Value
                case 'freespace'
                    % Create the popup window
                    popup = uifigure('Position', [100 100 300 200], 'Name', "Advanced Freespace Propagation Properties");
                    popup.Scrollable = 'on';

                    label11 = uilabel(popup, 'Text', 'No Advanced Properties for this Model');
                    label11.Position = [30 150 250 20];

                    % Create the submit button
                    submitButton = uibutton(popup, 'push');
                    submitButton.Text = 'Close';
                    submitButton.Position = [120 50 50 30];
                    submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed6();

                case 'rain'
                    % Create the popup window
                    popup = uifigure('Position', [100 100 300 200], 'Name', "Advanced Rain Propagation Properties");
                    popup.Scrollable = 'on';

                    label11 = uilabel(popup, 'Text', 'Rain Rate:');
                    label11.Position = [30 150 130 20];
                    editField11 = uieditfield(popup, 'numeric');
                    editField11.Position = [170 150 40 20];

                    label12 = uilabel(popup, 'Text', 'Tilt Angle:');
                    label12.Position = [30 100 130 20];
                    editField12 = uieditfield(popup, 'numeric');
                    editField12.Position = [170 100 40 20];

                    % Create the submit button
                    submitButton = uibutton(popup, 'push');
                    submitButton.Text = 'Submit';
                    submitButton.Position = [120 50 50 30];
                    submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed( ...
                        editField11.Value, ...
                        editField12.Value);

                case 'gas'
                    % Create the popup window
                    popup = uifigure('Position', [100 100 400 200], 'Name', "Advanced Gas Propagation Properties");
                    popup.Scrollable = 'on';

                    label11 = uilabel(popup, 'Text', 'Air Temparture:');
                    label11.Position = [30 150 230 20];
                    editField11 = uieditfield(popup, 'numeric');
                    editField11.Position = [270 150 40 20];

                    label21 = uilabel(popup, 'Text', 'Dry Air Pressure:');
                    label21.Position = [30 120 230 20];
                    editField21 = uieditfield(popup, 'numeric');
                    editField21.Position = [270 120 40 20];

                    label31 = uilabel(popup, 'Text', 'Water Vapour Density:');
                    label31.Position = [30 90 230 20];
                    editField31 = uieditfield(popup, 'numeric');
                    editField31.Position = [270 90 40 20];

                    % Create the submit button
                    submitButton = uibutton(popup, 'push');
                    submitButton.Text = 'Submit';
                    submitButton.Position = [180 50 50 30];
                    submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed1( ...
                        editField11.Value, ...
                        editField21.Value, ...
                        editField31.Value);
                    
                case 'fog'
                    % Create the popup window
                    popup = uifigure('Position', [100 100 300 200], 'Name', "Advanced Fog Propagation Properties");
                    popup.Scrollable = 'on';


                    label11 = uilabel(popup, 'Text', 'Air Temperature:');
                    label11.Position = [30 150 130 20];
                    editField11 = uieditfield(popup, 'numeric');
                    editField11.Position = [170 150 40 20];

                    label12 = uilabel(popup, 'Text', 'Liquid Water Density:');
                    label12.Position = [30 100 130 20];
                    editField12 = uieditfield(popup, 'numeric');
                    editField12.Position = [170 100 40 20];

                    % Create the submit button
                    submitButton = uibutton(popup, 'push');
                    submitButton.Text = 'Submit';
                    submitButton.Position = [120 50 50 30];
                    submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed2( ...
                        editField11.Value, ...
                        editField12.Value);


                case 'close-in'
                    popup = uifigure('Position', [100 100 450 150], 'Name', "Advanced Close-In Propagation Properties");
                    popup.Scrollable = 'on';

                    label11 = uilabel(popup, 'Text', 'Reference Distance:');
                    label11.Position = [30 100 160 20];
                    editField11 = uieditfield(popup, 'numeric');
                    editField11.Position = [170 100 40 20];

                    label12 = uilabel(popup, 'Text', 'Path Loss Exponent:');
                    label12.Position = [220 100 160 20];
                    editField12 = uieditfield(popup, 'numeric');
                    editField12.Position = [370 100 40 20];

                    label21 = uilabel(popup, 'Text', 'Standard Deviation:');
                    label21.Position = [30 70 160 20];
                    editField21 = uieditfield(popup, 'numeric');
                    editField21.Position = [170 70 40 20];

                    label22 = uilabel(popup, 'Text', 'Number of Data Points:');
                    label22.Position = [220 70 160 20];
                    editField22 = uieditfield(popup, 'numeric');
                    editField22.Position = [370 70 40 20];

                    % Create the submit button
                    submitButton = uibutton(popup, 'push');
                    submitButton.Text = 'Submit';
                    submitButton.Position = [180 30 50 30];
                    submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed3( ...
                        editField11.Value, ...
                        editField12.Value, ...
                        editField21.Value, ...
                        editField22.Value);
                    
                case 'longley-rain'
                    % Create the popup window
                    popup = uifigure('Position', [100 100 420 300], 'Name', "Advanced Longley-Rain Propagation Properties");
                    popup.Scrollable = 'on';

                    label11 = uilabel(popup, 'Text', 'Antenna Polarization:');
                    label11.Position = [30 250 160 20];
                    editField11 = uieditfield(popup, 'numeric');
                    editField11.Position = [170 250 40 20];

                    label12 = uilabel(popup, 'Text', 'Ground Conductivity:');
                    label12.Position = [220 250 160 20];
                    editField12 = uieditfield(popup, 'numeric');
                    editField12.Position = [370 250 40 20];

                    label21 = uilabel(popup, 'Text', 'Ground Permitivity:');
                    label21.Position = [30 220 160 20];
                    editField21 = uieditfield(popup, 'numeric');
                    editField21.Position = [170 220 40 20];

                    label22 = uilabel(popup, 'Text', 'Atmospheric Refractivity:');
                    label22.Position = [220 220 160 20];
                    editField22 = uieditfield(popup, 'numeric');
                    editField22.Position = [370 220 40 20];

                    label31 = uilabel(popup, 'Text', 'Time Variability Tolerance:');
                    label31.Position = [30 180 200 20];
                    editField31 = uieditfield(popup, 'numeric');
                    editField31.Position = [200 180 40 20];

                    label41 = uilabel(popup, 'Text', 'Situation Variability Tolerance:');
                    label41.Position = [30 150 200 20];
                    editField41 = uieditfield(popup, 'numeric');
                    editField41.Position = [200 150 40 20];


                    % Create the submit button
                    submitButton = uibutton(popup, 'push');
                    submitButton.Text = 'Submit';
                    submitButton.Position = [180 50 50 30];
                    submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed4( ...
                        editField11.Value, ...
                        editField12.Value, ...
                        editField21.Value, ...
                        editField22.Value, ...
                        editField31.Value, ...
                        editField41.Value);
                    
                case 'raytracing'
                    % Create the popup window
                    popup = uifigure('Position', [100 100 800 600], 'Name', "Advanced Ray Tracing Properties");
                    popup.Scrollable = 'on';

                    %% Surface Material pannel
                    % Create the first label and edit field
                    smcPanel = uipanel(popup,"Title", "Surface Material", "Position", [30 500 350 140]);


                    smcLabel = uilabel(smcPanel, 'Text', 'Surface Material Conductivity:');
                    smcLabel.Position = [15 90 180 20];
                    smcEditField = uieditfield(smcPanel, 'numeric', "Limits", [0 Inf], "Value", 0.0183);
                    smcEditField.Position = [200 90 40 20];

                    % Create the second label and edit field
                    smpLabel = uilabel(smcPanel, 'Text', 'Surface Material Permitivity:');
                    smpLabel.Position = [15 60 180 20];
                    smpEditField = uieditfield(smcPanel, 'numeric', "Limits", [0 Inf], "Value", 2.94);
                    smpEditField.Position = [200 60 40 20];

                    % Create the third label and edit field
                    smDropDownLabel = uilabel(smcPanel, 'Text', 'Surface Material');
                    smDropDownLabel.Position = [15 30 180 20];
                    smDropDown = uidropdown(smcPanel, 'Items', [ ...
                        "plasterboard", ...
                        "perfect-reflector", ...
                        "ceilingboard", ...
                        "chipboard", ...
                        "floorboard", ...
                        "concrete", ...
                        "brick", ...
                        "wood", ...
                        "glass", ...
                        "metal", ...
                        "water", ...
                        "vegetation", ...
                        "loam"]);
                    smDropDown.Position = [200 30 100 20];

                    %             editField31 = uieditfield(popup, 'numeric');
                    %             editField31.Position = [120 430 100 20];


                    %% Terrain Material Pannel
                    tmcPanel = uipanel(popup,"Title", "Terrain Material", "Position", [30 350 350 140]);


                    tmcLabel = uilabel(tmcPanel, 'Text', 'Terrain Material Conductivity:');
                    tmcLabel.Position = [15 90 180 20];
                    tmcEditField = uieditfield(tmcPanel, 'numeric', "Limits", [0 Inf], "Value", 0.0548);
                    tmcEditField.Position = [200 90 40 20];

                    % Create the second label and edit field
                    tmpLabel = uilabel(tmcPanel, 'Text', 'Terrain Material Permitivity:');
                    tmpLabel.Position = [15 60 180 20];
                    tmpEditField = uieditfield(tmcPanel, 'numeric', "Limits", [0 Inf], "Value", 5.31);
                    tmpEditField.Position = [200 60 40 20];

                    % Create the third label and edit field
                    tmDropDownLabel = uilabel(tmcPanel, 'Text', 'Terrain Material');
                    tmDropDownLabel.Position = [15 30 180 20];
                    tmDropDown = uidropdown(tmcPanel, 'Items', [ ...
                        "concrete", ...
                        "perfect-reflector", ...
                        "brick", ...
                        "water", ...
                        "vegetation", ...
                        "loam"]);
                    tmDropDown.Position = [200 30 100 20];


                    %% Building Material Pannel
                    bmcPanel = uipanel(popup,"Title", "Building Material", "Position", [30 200 350 140]);

                    bmcLabel = uilabel(bmcPanel, 'Text', 'Building Material Conductivity:');
                    bmcLabel.Position = [15 90 180 20];
                    bmcEditField = uieditfield(bmcPanel, 'numeric', "Limits", [0 Inf], "Value", 0.0548);
                    bmcEditField.Position = [200 90 40 20];

                    bmpLabel = uilabel(bmcPanel, 'Text', 'Building Material Permitivity:');
                    bmpLabel.Position = [15 60 180 20];
                    bmpEditField = uieditfield(bmcPanel, 'numeric', "Limits", [0 Inf], "Value", 5.31);
                    bmpEditField.Position = [200 60 40 20];

                    bmDropDownLabel = uilabel(bmcPanel, 'Text', 'Building Material');
                    bmDropDownLabel.Position = [15 30 180 20];
                    bmDropDown = uidropdown(bmcPanel, 'Items', [ ...
                        "concrete", ...
                        "perfect-reflector", ...
                        "brick", ...
                        "wood", ...
                        "glass", ...
                        "metal", ...
                        "loam"]);
                    bmDropDown.Position = [200 30 100 20];

                    %% Ray Tracing Panel
                    rtPanel = uipanel(popup, "Title", "Ray Tracing Pannel", "Position", [400 440 350 200]);

                    mrplLabel = uilabel(rtPanel, "Text", "Max Relative Path Loss");
                    mrplLabel.Position = [15 150 180 20];
                    mrplEditField = uieditfield(rtPanel, 'numeric', "Limits", [0 Inf], "Value", 40);
                    mrplEditField.Position = [200 150 40 20];

                    maplLabel = uilabel(rtPanel, "Text", "Max Absolute Path Loss");
                    maplLabel.Position = [15 120 180 20];
                    maplEditField = uieditfield(rtPanel, 'numeric', "Limits", [0 Inf], "Value", Inf);
                    maplEditField.Position = [200 120 40 20];

                    mdDropDownLabel = uilabel(rtPanel,"Text","Max Diffraction");
                    mdDropDownLabel.Position = [15 90 180 20];
                    mdDropDown = uidropdown(rtPanel,"Items",[ ...
                        "0", ...
                        "1", ...
                        "2"]);
                    mdDropDown.Position = [200 90 40 20];

                    mrLabel = uilabel(rtPanel, "Text", "Max Reflections");
                    mrLabel.Position = [15 60 180 20];
                    mrEditField = uieditfield(rtPanel, 'numeric', "Limits",[0 10]);
                    mrEditField.Value = 2;
                    mrEditField.Position = [200 60 40 20];

                    asLabel = uilabel(rtPanel,"Text", "Angular Separation");
                    asLabel.Position = [15 30 180 20];
                    asDropDown = uidropdown(rtPanel, "Items",[ ...
                        "medium", ...
                        "high", ...
                        "low"]);
                    asDropDown.Position = [200 30 100 20];


                    % Create the submit button
                    submitButton = uibutton(popup, 'push');
                    submitButton.Text = 'Submit';
                    submitButton.Position = [350 100 100 20];
                    %             submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed( ...
                    %                 editField11.Value, ...
                    %                 editField21.Value, ...
                    %                 editField31);
                    submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed5();
                case "userdefined"
                    % Create the popup window
%                     popup = uifigure('Position', [100 100 300 200], 'Name', "Advanced User Defined Propagation Properties");
%                     popup.Scrollable = 'on';
% 
%                     label11 = uilabel(popup, 'Text', 'Please Upload the file containing the code for the userdefined function of propagation model');
%                     label11.Position = [30 150 250 20];
%                     
%                     label21 = uilabel(popup, 'Text', 'Input Arguments for the function must be tx - TransmitterSite object, and rx - ReceiverSite Object');
%                     label21.Position = [30 130 250 20];
                    
%                     uploadButton = uibutton(popup, "push");
%                     uploadButton.Text = 'Upload';
%                     uploadButton.Position = [120 100 50 30];
%                     uploadButton.ButtonPushedFcn = @(~,~)uploadFileCallback();

                    % Create a new uifigure for the pop-up window
                    popupFig = uifigure('Name', 'File Upload', 'Position', [100 100 620 200]);
                    popupFig.Scrollable = 'on';


                    % Add a layout to the figure to arrange components
                    layout = uigridlayout(popupFig);
                    layout.RowHeight = {'1x', 'fit', 'fit'};
                    label11 = uilabel(popupFig, 'Text', 'Please Upload the file containing the code for the userdefined function of propagation model');
                    label11.Position = [30 150 600 20];
                    
                    label21 = uilabel(popupFig, 'Text', 'Input Arguments for the function must be tx - TransmitterSite object, and rx - ReceiverSite Object');
                    label21.Position = [30 130 600 20];
                    
                    % Add a uibutton for uploading the file
                    uploadButton = uibutton(popupFig, 'Text', 'Upload File', 'Position', [300 100 50 30], ...
                        'ButtonPushedFcn', @(~,~)uploadFileCallback(popupFig, popupFig));


                    % Create the submit button
                    submitButton = uibutton(popupFig, 'push');
                    submitButton.Text = 'Close';
                    submitButton.Position = [300 50 50 30];
                    submitButton.ButtonPushedFcn = @(~,~) submitButtonPushed6();

            end
            
            % Callback function for the "Upload File" button
            function uploadFileCallback(app, popupFig)
                % Open a file dialog for the user to select the file
%                 [filename, filepath] = uigetfile({'*.txt'; '*.csv'; '*.mat'}, 'Select File');
                
                [filename, filepath] = uigetfile('*.mat', 'Select .mat file');
                if isequal(filename, 0) || isequal(filepath, 0)
                    % User canceled the file selection
                    return;
                end

%                 % Check if the user selected a file
%                 if ischar(filename) && ischar(filepath)
%                     % File selected, do something with the file (e.g., read data, process, etc.)
%                     % Here you can add your code to handle the uploaded file
% %                     disp(['Selected file: ', fullfile(filepath, filename)]);
%                 else
%                     % User canceled the file selection, do nothing or display an error message
% %                     disp('File selection canceled.');
%                 end
% 
%                 % Close the pop-up window after file selection
% %                 delete(popupFig);
            end


            % Callback function for the "Upload File" button
%             function uploadFileCallback()
%                 % Open a file dialog for the user to select the file
%                 [filename, filepath] = uigetfile({'*.txt'; '*.csv'; '*.mat'}, 'Select File');
% 
%                 % Check if the user selected a file
%                 if ischar(filename) && ischar(filepath)
%                     % File selected, do something with the file (e.g., read data, process, etc.)
%                     % Here you can add your code to handle the uploaded file
%                     disp(['Selected file: ', fullfile(filepath, filename)]);
%                 else
%                     % User canceled the file selection, do nothing or display an error message
%                     disp('File selection canceled.');
%                 end
% 
%                 % Close the pop-up window after file selection
%                 delete(popupFig);
%             end


            % Function to handle the submit button press
            function submitButtonPushed(input1, input2)

                app.propRainRate = input1;
                app.propRainTilt = input2;

                % Close the popup window
                close(popup);
            end

            % Function to handle the submit button press
            function submitButtonPushed1(input1, input2, input3)

                app.propGasAirTemp = input1;
                app.propGasDryAirPressure = input2;
                app.propGasWaterVapourDensity = input3;

                % Close the popup window
                close(popup);
            end

            % Function to handle the submit button press
            function submitButtonPushed2(input1, input2)

                app.propFogAirTemp = input1;
                app.propFogLiquidWaterDensity = input2;

                % Close the popup window
                close(popup);
            end

            % Function to handle the submit button press
            function submitButtonPushed3(input1, input2, input3, input4)

                app.propCloseInReferenceDistance = input1;
                app.propCloseInPathLossExponent = input2;

                app.propCloseInStandardDeviation = input3;
                app.propCloseInNumOfDataPoints = input4;

                
                % Close the popup window
                close(popup);
            end

            % Function to handle the submit button press
            function submitButtonPushed4(input1, input2, input3, input4, input5, input6)
                % Perform any desired actions with the input values
                app.propLongleyAntennaPolarization = input1;
                app.propLongleyGroundConductivity = input2;
                app.propLongleyGroundPermitivity = input3;
                app.propLongleyAtmosphericRefractivity = input4;
                app.propLongleyTimeVariabilityTolerance = input5;
                app.propLongleySituationVariabilityTolerance = input6;
        
        
                % Close the popup window
                close(popup);
            end

            % Function to handle the submit button press
            function submitButtonPushed5(input1, input2, input3, input4, input5, input6, input7, input8, input9, input10, input11, input12, input13, input14)
                % Perform any desired actions with the input values
                app.propRaytracingSurfaceMaterialConductivity = input1;
                app.propRaytracingSurfaceMaterialPermitivity = input2;
                app.propRaytracingSurfaceMaterial = input3;
                app.propRaytracingTerrainMaterialConductivity = input4;
                app.propRaytracingTerrainMaterialPermitivity = input5;
                app.propRaytracingTerrainMaterial = input6;
                app.propRaytracingBuildingMaterialConductivity = input7;
                app.propRaytracingBuildingMaterialPermitivity = input8;
                app.propRaytracingBuildingMaterial = input9;
                app.propRaytracingMaxRelativePathLoss = input10;
                app.propRaytracingMaxAbsolutePathLoss = input11;
                app.propRaytracingMaxdiffraction = input12;
                app.propRaytracingMaxReflection = input13;
                app.propRaytracingAngularSeparation = input14;
                % Close the popup window
                close(popup);
            end
            
            % Function to handle the submit button press
            function submitButtonPushed6()
                % Close the popup window
                close(popup);
            end


        end

        % Button pushed function: DesignCustomAntennaButton
        function DesignCustomAntennaButtonPushed(app, event)
            antennaDesigner
        end

        % Button pushed function: CreateFromPresetButton
        function CreateFromPresetButtonPushed(app, event)
            %Create a new transmitter

            %If no name is provided, use the preset design as name
            if app.NameofAntennaEditField.Value == ""
                name = app.PresetTypesDropDown.Value;
            else
                name = app.NameofAntennaEditField.Value;
            end
            
            %Empty name entry box
            app.NameofAntennaEditField.Value = "";
            %Create antenna
            newAntenna = Antenna(name,...
                eval(app.PresetTypesDropDown.Value),...
                app.OperatingFrequencyEditField.Value);

            %Assign new tranmitter to transmitter list
            app.AntennaList.list{end+1} = newAntenna;
            app.SelectAntennaDropDown.Items{end+1} = newAntenna.Name;
            
            %Display new transmitter for user
            pattern(newAntenna.Sim, newAntenna.OpFreq);
        
        end

        % Value changed function: SelectAntennaDropDown
        function SelectAntennaDropDownValueChanged(app, event)
            
            app.ActiveAntenna = app.AntennaList.list{app.SelectAntennaDropDown.Value};
            
        end

        % Clicked callback: TransmitterSitesListBox
        function TransmitterSitesListBoxClicked(app, event)
            app.ActiveTransmitterSite = app.TransmitterSites.list{event.InteractionInformation.Item};

        end

        % Clicked callback: RecieverSitesListBox
        function RecieverSitesListBoxClicked(app, event)
            app.ActiveRecieverSite = app.RecieverSites.get(event.InteractionInformation.Item);
            
        end

        % Button pushed function: SetAntennatoActiveAntennaButton
        function SetAntennatoActiveAntennaButtonPushed(app, event)
            app.ActiveAntenna = app.AntennaList.list{app.SelectAntennaDropDown.Value};
            app.Label.Text = ['Active Antenna: ' app.ActiveAntenna.Name];
        end

        % Button pushed function: LoadAntennaFromFileButton
        function LoadAntennaFromFileButtonPushed(app, event)
            userAntenna = Antenna('Custom',dipole,144.5e6);
            [file, path] = uigetfile();
            load(file);
            pattern(Design, 144.5e6);
            if app.NameofAntennaEditField.Value ~= ""
                userAntenna.Name = app.NameofAntennaEditField.Value;
            end
            userAntenna.Type = class(Design);
            userAntenna.Sim = Design;
            
            userAntenna.OpFreq = app.OperatingFrequencyEditField.Value;

            app.AntennaList.list{end+1} = userAntenna;
            app.SelectAntennaDropDown.Items{end+1} = userAntenna.Name;
            
        end

        % Value changed function: PropagationModelDropDown
        function PropagationModelDropDownValueChanged(app, event)
            app.PropagationModel = app.PropagationModelDropDown.Value;
            
        end

        % Button pushed function: GenerateDatasetButton
        function GenerateDatasetButtonPushed(app, event)
            index = 1;
            n = app.nEditField.Value;
            m = app.mEditField.Value;
            
            switch(app.PropagationModel)
                
                case "freespace"
                     for row = 1:n
                        for col = 1:m
                            app.ActiveTransmitterSite = app.CoverageData{index};
                            Data = coverage(app.ActiveTransmitterSite, app.PropagationModel);
                            writetable(Data.Data);

                            name = [num2str(row), '-', num2str(col)];
                            disp(name)
                            index = index + 1;
                        end
                    end
                case "close-in"
                     for row = 1:n
                        for col = 1:m
                            app.ActiveTransmitterSite = app.CoverageData{index};
                            Data = coverage(app.ActiveTransmitterSite, app.PropagationModel);
                            writetable(Data.Data);

                            name = [num2str(row), '-', num2str(col)];
                            disp(name)
                            index = index + 1;
                        end
                    end                    
                case "rain"
                     for row = 1:n
                        for col = 1:m
                            app.ActiveTransmitterSite = app.CoverageData{index};
                            Data = coverage(app.ActiveTransmitterSite, app.PropagationModel);
                            writetable(Data.Data);

                            name = [num2str(row), '-', num2str(col)];
                            disp(name)
                            index = index + 1;
                        end
                    end                    
                case "gas"
                     for row = 1:n
                        for col = 1:m
                            app.ActiveTransmitterSite = app.CoverageData{index};
                            Data = coverage(app.ActiveTransmitterSite, app.PropagationModel);
                            writetable(Data.Data);

                            name = [num2str(row), '-', num2str(col)];
                            disp(name)
                            index = index + 1;
                        end
                    end                    
                case "fog"
                     for row = 1:n
                        for col = 1:m
                            app.ActiveTransmitterSite = app.CoverageData{index};
                            Data = coverage(app.ActiveTransmitterSite, app.PropagationModel);
                            writetable(Data.Data);

                            name = [num2str(row), '-', num2str(col)];
                            disp(name)
                            index = index + 1;
                        end
                    end                    
                case "longley-rice"
                     for row = 1:n
                        for col = 1:m
                            app.ActiveTransmitterSite = app.CoverageData{index};
                            Data = coverage(app.ActiveTransmitterSite, app.PropagationModel);
                            writetable(Data.Data);

                            name = [num2str(row), '-', num2str(col)];
                            disp(name)
                            index = index + 1;
                        end
                    end
                case "raytracing"

                    viewer1 = siteviewer();

                     for row = 1:n
                        for col = 1:m
                            app.ActiveTransmitterSite = app.CoverageData{index};
                            Data = coverage(app.ActiveTransmitterSite, app.PropagationModel, map=viewer1);
                            writetable(Data.Data);

                            name = [num2str(row), '-', num2str(col)];
                            disp(name)
                            index = index + 1;
                        end
                    end                    
                case "userdefined"
                     for row = 1:n
                        for col = 1:m
                            app.ActiveTransmitterSite = app.CoverageData{index};
                            Data = coverage(app.ActiveTransmitterSite, app.PropagationModel);
                            writetable(Data.Data);

                            name = [num2str(row), '-', num2str(col)];
                            disp(name)
                            index = index + 1;
                        end
                    end
            end %switch
            disp("Finished")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnvironmentalPropertiesPanel = uipanel(app.UIFigure);
            app.EnvironmentalPropertiesPanel.Title = 'Environmental Properties';
            app.TabGroup = uitabgroup(app.EnvironmentalPropertiesPanel);
            app.TargetRegionTab = uitab(app.TabGroup);
            app.TargetRegionTab.Title = 'Target Region';
            app.NorthEastLatitudeEditFieldLabel = uilabel(app.TargetRegionTab);
            app.NorthEastLatitudeEditFieldLabel.Text = 'North-East Latitude';
            app.NorthEastLatitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.NorthEastLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.NorthEastLatitudeEditField.Value = 33.1947;
            app.SouthWestLongitudeLabel = uilabel(app.TargetRegionTab);
            app.SouthWestLongitudeLabel.Text = 'South-West Latitude';
            app.SouthWestLatitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.SouthWestLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.SouthWestLatitudeEditField.Value = 33.2647;
            app.PlotTargetRegionButton = uibutton(app.TargetRegionTab, 'push');
            app.PlotTargetRegionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTargetRegionButtonPushed, true);
            app.PlotTargetRegionButton.Text = 'Plot Target Region';
            app.NorthEastLongitudeEditFieldLabel = uilabel(app.TargetRegionTab);
            app.NorthEastLongitudeEditFieldLabel.Text = 'North-East Longitude';
            app.NorthEastLongitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.NorthEastLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.NorthEastLongitudeEditField.Value = -97.1979;
            app.SouthWestLongitudeEditField_2Label = uilabel(app.TargetRegionTab);
            app.SouthWestLongitudeEditField_2Label.Text = 'South-West Longitude';
            app.SouthWestLongitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.SouthWestLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.SouthWestLongitudeEditField.Value = -97.1045;
            app.PropagationModelTab = uitab(app.TabGroup);
            app.PropagationModelTab.Title = 'Propagation Model';
            app.PropagationModelDropDownLabel = uilabel(app.PropagationModelTab);
            app.PropagationModelDropDownLabel.Text = 'Propagation Model';
            app.PropagationModelDropDown = uidropdown(app.PropagationModelTab);
            app.PropagationModelDropDown.Items = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rice', 'raytracing', 'user-defined'};
            app.PropagationModelDropDown.ItemsData = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rice', 'raytracing', 'userdefined'};
            app.PropagationModelDropDown.ValueChangedFcn = createCallbackFcn(app, @PropagationModelDropDownValueChanged, true);
            app.PropagationModelDropDown.Value = 'freespace';
            app.AdvancedPropertiesButton = uibutton(app.PropagationModelTab, 'push');
            app.AdvancedPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedPropertiesButtonPushed, true);
            app.AdvancedPropertiesButton.Text = 'Advanced Properties';
            app.AdvancedRayTracingPropertiesPanel = uipanel(app.UIFigure);
            app.AdvancedRayTracingPropertiesPanel.Title = 'Advanced Ray Tracing Properties';
            app.AdvancedRayTracingPropertiesPanel.Visible = 'off';
            app.RayTracingPanel = uipanel(app.AdvancedRayTracingPropertiesPanel);
            app.RayTracingPanel.Title = 'Ray Tracing';
            app.AngularSeparationDropDownLabel = uilabel(app.RayTracingPanel);
            app.AngularSeparationDropDownLabel.Text = 'Angular Separation';
            app.AngularSeparationDropDown = uidropdown(app.RayTracingPanel);
            app.AngularSeparationDropDown.Items = {'medium', 'high', 'low'};
            app.AngularSeparationDropDown.Value = 'medium';
            app.MaxReflectionsEditFieldLabel = uilabel(app.RayTracingPanel);
            app.MaxReflectionsEditFieldLabel.Text = 'Max Reflections';
            app.MaxReflectionsEditField = uieditfield(app.RayTracingPanel, 'numeric');
            app.MaxReflectionsEditField.Limits = [0 10];
            app.MaxReflectionsEditField.Value = 2;
            app.MaxDiffractionsDropDownLabel = uilabel(app.RayTracingPanel);
            app.MaxDiffractionsDropDownLabel.Text = 'Max Diffractions';
            app.MaxDiffractionsDropDown = uidropdown(app.RayTracingPanel);
            app.MaxDiffractionsDropDown.Items = {'0', '1', '2'};
            app.MaxDiffractionsDropDown.Value = '0';
            app.MaxAbsolutePathLossEditFieldLabel = uilabel(app.RayTracingPanel);
            app.MaxAbsolutePathLossEditFieldLabel.Text = 'Max Absolute Path Loss';
            app.MaxAbsolutePathLossEditField = uieditfield(app.RayTracingPanel, 'numeric');
            app.MaxAbsolutePathLossEditField.Limits = [0 Inf];
            app.MaxAbsolutePathLossEditField.Value = Inf;
            app.MaxRelativePathLossEditFieldLabel = uilabel(app.RayTracingPanel);
            app.MaxRelativePathLossEditFieldLabel.Text = 'Max Relative Path Loss';
            app.MaxRelativePathLossEditField = uieditfield(app.RayTracingPanel, 'numeric');
            app.MaxRelativePathLossEditField.Limits = [0 Inf];
            app.MaxRelativePathLossEditField.Value = 40;
            app.BuildingMaterialPanel = uipanel(app.AdvancedRayTracingPropertiesPanel);
            app.BuildingMaterialPanel.Title = 'Building Material';
            app.BuildingMaterialsDropDownLabel = uilabel(app.BuildingMaterialPanel);
            app.BuildingMaterialsDropDownLabel.Text = 'Building Materials';
            app.BuildingMaterialsDropDown = uidropdown(app.BuildingMaterialPanel);
            app.BuildingMaterialsDropDown.Items = {'concrete', 'perfect-reflector', 'brick', 'water', 'vegetation', 'loam'};
            app.BuildingMaterialsDropDown.Value = 'concrete';
            app.BuildingMaterialPermittivityEditFieldLabel = uilabel(app.BuildingMaterialPanel);
            app.BuildingMaterialPermittivityEditFieldLabel.Text = 'Building Material Permittivity';
            app.BuildingMaterialPermittivityEditField = uieditfield(app.BuildingMaterialPanel, 'numeric');
            app.BuildingMaterialPermittivityEditField.Limits = [0 Inf];
            app.BuildingMaterialPermittivityEditField.Value = 5.31;
            app.BuildingMaterialConductivityEditFieldLabel = uilabel(app.BuildingMaterialPanel);
            app.BuildingMaterialConductivityEditFieldLabel.Text = 'Building Material Conductivity';
            app.BuildingMaterialConductivityEditField = uieditfield(app.BuildingMaterialPanel, 'numeric');
            app.BuildingMaterialConductivityEditField.Limits = [0 Inf];
            app.BuildingMaterialConductivityEditField.Value = 0.0548;
            app.TerrainMaterialPanel = uipanel(app.AdvancedRayTracingPropertiesPanel);
            app.TerrainMaterialPanel.Title = 'Terrain Material';
            app.SurfaceMaterialDropDownLabel = uilabel(app.TerrainMaterialPanel);
            app.SurfaceMaterialDropDownLabel.Text = 'Surface Material';
            app.TerrainMaterialDropDown = uidropdown(app.TerrainMaterialPanel);
            app.TerrainMaterialDropDown.Items = {'plasterboard', 'perfect-reflector', 'ceilingboard', 'chipboard', 'floorboard', 'concrete', 'brick', 'wood', 'glass', 'metal', 'water', 'vegetation', 'loam'};
            app.TerrainMaterialDropDown.Value = 'plasterboard';
            app.SurfaceMaterialPermittivityEditFieldLabel = uilabel(app.TerrainMaterialPanel);
            app.SurfaceMaterialPermittivityEditFieldLabel.Text = 'Surface Material Permittivity';
            app.TerrainMaterialPermittivityEditField = uieditfield(app.TerrainMaterialPanel, 'numeric');
            app.TerrainMaterialPermittivityEditField.Limits = [0 Inf];
            app.TerrainMaterialPermittivityEditField.Value = 2.94;
            app.SurfaceMaterialConductivityEditFieldLabel = uilabel(app.TerrainMaterialPanel);
            app.SurfaceMaterialConductivityEditFieldLabel.Text = 'Surface Material Conductivity';
            app.TerrainMaterialConductivityEditField = uieditfield(app.TerrainMaterialPanel, 'numeric');
            app.TerrainMaterialConductivityEditField.Limits = [0 Inf];
            app.TerrainMaterialConductivityEditField.Value = 0.0183;
            app.SurfaceMaterialPanel = uipanel(app.AdvancedRayTracingPropertiesPanel);
            app.SurfaceMaterialPanel.Title = 'Surface Material';
            app.TerrainMaterialDropDownLabel = uilabel(app.SurfaceMaterialPanel);
            app.TerrainMaterialDropDownLabel.Text = 'Terrain Material';
            app.SurfaceMaterialDropDown = uidropdown(app.SurfaceMaterialPanel);
            app.SurfaceMaterialDropDown.Items = {'concrete', 'perfect-reflector', 'brick', 'water', 'vegetation', 'loam'};
            app.SurfaceMaterialDropDown.Value = 'concrete';
            app.SurfaceMaterialPermittivityEditField_2Label = uilabel(app.SurfaceMaterialPanel);
            app.SurfaceMaterialPermittivityEditField_2Label.Text = 'Surface Material Permittivity';
            app.SurfaceMaterialPermittivityEditField_2 = uieditfield(app.SurfaceMaterialPanel, 'numeric');
            app.SurfaceMaterialPermittivityEditField_2.Limits = [0 Inf];
            app.SurfaceMaterialPermittivityEditField_2.Value = 5.31;
            app.SurfaceMaterialConductivityEditField_2Label = uilabel(app.SurfaceMaterialPanel);
            app.SurfaceMaterialConductivityEditField_2Label.Text = 'Surface Material Conductivity';
            app.SurfaceMaterialConductivityEditField_2 = uieditfield(app.SurfaceMaterialPanel, 'numeric');
            app.SurfaceMaterialConductivityEditField_2.Limits = [0 Inf];
            app.SurfaceMaterialConductivityEditField_2.Value = 0.0548;
            app.MapPanel_2 = uipanel(app.UIFigure);
            app.MapPanel_2.Title = 'Map Panel';
            app.MapPanel = uipanel(app.MapPanel_2);
            app.BaseMapDropDownLabel = uilabel(app.MapPanel_2);
            app.BaseMapDropDownLabel.Text = 'Base Map';
            app.BaseMapDropDown = uidropdown(app.MapPanel_2);
            app.BaseMapDropDown.Items = {'streets', 'darkwater', 'grayland', 'bluegreen', 'grayterrain', 'colorterrain', 'landcover', 'streets-light', 'streets-dark', 'satellite', 'topographic'};
            app.BaseMapDropDown.Value = 'streets';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.SwarmPropertiesTab = uitab(app.TabGroup3);
            app.SwarmPropertiesTab.Title = 'Swarm Properties';
            app.Panel = uipanel(app.SwarmPropertiesTab);
            app.TabGroup_2 = uitabgroup(app.Panel);
            app.AntennasTab = uitab(app.TabGroup_2);
            app.AntennasTab.Title = 'Antennas';
            app.PresetTypesDropDownLabel = uilabel(app.AntennasTab);
            app.PresetTypesDropDownLabel.Text = 'Preset Types';
            app.PresetTypesDropDown = uidropdown(app.AntennasTab);
            app.PresetTypesDropDown.Items = {'cavity', 'cavityCircular', 'bicone', 'discone', 'monocone', 'biconeStrip', 'disconeStrip', 'draRectangular', 'draCylindrical', 'bowtieRounded', 'bowtieTriangular', 'biquad', 'dipole', 'dipoleBlade', 'dipoleCrossed', 'dipoleCycloid', 'dipoleCylindrical', 'dipoleFolded', 'dipoleHelix', 'dipoleHelixMultifilar', 'dipoleJ', 'dipoleMeander', 'dipoleVee', 'rhombic', 'wireStack', 'fractalKoch', 'fractalGasket', 'fractalCarpet', 'fractalIsland', 'fractalSnowflake', 'helix', 'dipoleHelix', 'helixMultifilar', 'dipoleHelixMultifilar', 'horn', 'hornConical', 'hornConicalCorrugated', 'hornCorrugated', 'hornPotter', 'hornRidge', 'hornScrimp', 'loopCircular', 'loopRectangular', 'invertedF', 'invertedL', 'invertedFcoplanar', 'invertedLcoplanar', 'monopole', 'monopoleCylindrical', 'monopoleRadial', 'monopoleTopHat', 'patchMicrostrip', 'patchMicrostripCircular', 'patchMicrostripElliptical', 'patchMicrostripInsetfed', 'patchMicrostripEnotch', 'patchMicrostripHnotch', 'patchMicrostripTriangular', 'pifa', 'cassegrain', 'cassegrainOffset', 'customDualReflectors', 'gregorian', 'gregorianOffset', 'reflectorParabolic', 'reflectorCylindrical', 'reflectorSpherical', 'reflector', 'reflectorCircular', 'reflectorCorner', 'reflectorGrid', 'slot', 'vivaldi', 'vivaldiAntipodal', 'vivaldiOffsetCavity', 'spiralArchimedean', 'spiralEquiangular', 'spiralRectangular', 'waveguideSlotted', 'waveguide', 'waveguideCircular', 'waveguideRidge', 'quadCustom', 'lpda', 'yagiUda', 'cloverleaf', 'birdcage', 'sectorInvertedAmos', 'linearArray', 'rectangularArray', 'conformalArray', 'circularArray'};
            app.PresetTypesDropDown.Value = 'yagiUda';
            app.NameofAntennaEditFieldLabel = uilabel(app.AntennasTab);
            app.NameofAntennaEditFieldLabel.Text = 'Name of Antenna';
            app.NameofAntennaEditField = uieditfield(app.AntennasTab, 'text');
            app.NameofAntennaEditField.Placeholder = 'Name';
            app.DesignCustomAntennaButton = uibutton(app.AntennasTab, 'push');
            app.DesignCustomAntennaButton.ButtonPushedFcn = createCallbackFcn(app, @DesignCustomAntennaButtonPushed, true);
            app.DesignCustomAntennaButton.Text = ' Design Custom Antenna ';
            app.CreateFromPresetButton = uibutton(app.AntennasTab, 'push');
            app.CreateFromPresetButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFromPresetButtonPushed, true);
            app.CreateFromPresetButton.Text = 'Create From Preset';
            app.OperatingFrequencyEditFieldLabel = uilabel(app.AntennasTab);
            app.OperatingFrequencyEditFieldLabel.Text = 'Operating Frequency';
            app.OperatingFrequencyEditField = uieditfield(app.AntennasTab, 'numeric');
            app.OperatingFrequencyEditField.Value = 144500000;
            app.SelectAntennaDropDownLabel = uilabel(app.AntennasTab);
            app.SelectAntennaDropDownLabel.Text = 'Select Antenna ';
            app.SelectAntennaDropDown = uidropdown(app.AntennasTab);
            app.SelectAntennaDropDown.Items = {};
            app.SelectAntennaDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.SelectAntennaDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAntennaDropDownValueChanged, true);
            app.SelectAntennaDropDown.Placeholder = 'Create Antenna';
            app.SelectAntennaDropDown.Value = {};
            app.SetAntennatoActiveAntennaButton = uibutton(app.AntennasTab, 'push');
            app.SetAntennatoActiveAntennaButton.ButtonPushedFcn = createCallbackFcn(app, @SetAntennatoActiveAntennaButtonPushed, true);
            app.SetAntennatoActiveAntennaButton.Text = 'Set Antenna to Active Antenna';
            app.Label = uilabel(app.AntennasTab);
            app.Label.Text = '';
            app.LoadAntennaFromFileButton = uibutton(app.AntennasTab, 'push');
            app.LoadAntennaFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAntennaFromFileButtonPushed, true);
            app.LoadAntennaFromFileButton.Text = 'Load Antenna From File';
            app.TransmitterSiteTab = uitab(app.TabGroup_2);
            app.TransmitterSiteTab.Title = 'Transmitter Site';
            app.AntennaHeightmetersEditFieldLabel = uilabel(app.TransmitterSiteTab);
            app.AntennaHeightmetersEditFieldLabel.Text = 'Antenna Height (meters)';
            app.AntennaHeightmetersEditField = uieditfield(app.TransmitterSiteTab, 'numeric');
            app.AntennaHeightmetersEditField.Value = 60;
            app.TransmitterPowerwattsEditFieldLabel = uilabel(app.TransmitterSiteTab);
            app.TransmitterPowerwattsEditFieldLabel.Text = 'Transmitter Power (watts)';
            app.TransmitterPowerwattsEditField = uieditfield(app.TransmitterSiteTab, 'numeric');
            app.TransmitterPowerwattsEditField.Value = 15;
            app.NameofSiteEditFieldLabel = uilabel(app.TransmitterSiteTab);
            app.NameofSiteEditFieldLabel.Text = 'Name of Site';
            app.TransmitterNameofSiteEditField = uieditfield(app.TransmitterSiteTab, 'text');
            app.TransmitterNameofSiteEditField.Value = 'DiscoveryPark';
            app.TransmitterAddSiteButton = uibutton(app.TransmitterSiteTab, 'push');
            app.TransmitterAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitterAddSiteButtonPushed, true);
            app.TransmitterAddSiteButton.Text = 'Add Site';
            app.LongitudeEditFieldLabel = uilabel(app.TransmitterSiteTab);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.TransmitterLongitudeEditField = uieditfield(app.TransmitterSiteTab, 'numeric');
            app.TransmitterLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmitterLongitudeEditField.Value = -97.1526;
            app.LatitudeEditFieldLabel = uilabel(app.TransmitterSiteTab);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.TransmiterLatitudeEditField = uieditfield(app.TransmitterSiteTab, 'numeric');
            app.TransmiterLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmiterLatitudeEditField.Value = 33.2538;
            app.ReceiverSiteTab = uitab(app.TabGroup_2);
            app.ReceiverSiteTab.Title = 'Receiver Site';
            app.ReceiverSentivityEditFieldLabel = uilabel(app.ReceiverSiteTab);
            app.ReceiverSentivityEditFieldLabel.Text = 'Receiver Sentivity';
            app.ReceiverSentivityEditField = uieditfield(app.ReceiverSiteTab, 'numeric');
            app.ReceiverSentivityEditField.Value = -90;
            app.NameofSiteEditField_2Label = uilabel(app.ReceiverSiteTab);
            app.NameofSiteEditField_2Label.Text = 'Name of Site';
            app.ReceiverNameofSiteEditField = uieditfield(app.ReceiverSiteTab, 'text');
            app.ReceiverNameofSiteEditField.Value = 'UNTUnion';
            app.LatitudeEditField_2Label = uilabel(app.ReceiverSiteTab);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.ReceiverLatitudeEditField = uieditfield(app.ReceiverSiteTab, 'numeric');
            app.ReceiverLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLatitudeEditField.Value = 33.2107;
            app.LongitudeEditField_2Label = uilabel(app.ReceiverSiteTab);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.ReceiverLongitudeEditField = uieditfield(app.ReceiverSiteTab, 'numeric');
            app.ReceiverLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLongitudeEditField.Value = -97.1473;
            app.ReceiverAddSiteButton = uibutton(app.ReceiverSiteTab, 'push');
            app.ReceiverAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiverAddSiteButtonPushed, true);
            app.ReceiverAddSiteButton.Text = 'Add Site';
            app.MaximumRangemEditFieldLabel = uilabel(app.ReceiverSiteTab);
            app.MaximumRangemEditFieldLabel.Text = 'Maximum Range(m)';
            app.MaximumRangemEditField = uieditfield(app.ReceiverSiteTab, 'numeric');
            app.FileUploadTab = uitab(app.TabGroup_2);
            app.FileUploadTab.Title = 'File Upload';
            app.UploadFileButton = uibutton(app.FileUploadTab, 'push');
            app.UploadFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFileButtonPushed, true);
            app.UploadFileButton.Text = 'Upload File';
            app.PathofFileUploadedEditFieldLabel = uilabel(app.FileUploadTab);
            app.PathofFileUploadedEditFieldLabel.Text = 'Path of File Uploaded';
            app.PathofFileUploadedEditField = uieditfield(app.FileUploadTab, 'text');
            app.PlotIdealcoverageAreaButton = uibutton(app.Panel, 'push');
            app.PlotIdealcoverageAreaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIdealcoverageAreaButtonPushed, true);
            app.PlotIdealcoverageAreaButton.Text = 'Plot Ideal coverage Area';
            app.TabGroup2 = uitabgroup(app.Panel);
            app.DirectionalAntennaTab = uitab(app.TabGroup2);
            app.DirectionalAntennaTab.Title = 'Directional Antenna';
            app.TiltAngleEditFieldLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAngleEditFieldLabel.Text = 'Tilt Angle';
            app.TiltAngleEditField = uieditfield(app.DirectionalAntennaTab, 'numeric');
            app.TiltAngleEditField.Value = 90;
            app.PlotDirectivityPatternButton = uibutton(app.DirectionalAntennaTab, 'push');
            app.PlotDirectivityPatternButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDirectivityPatternButtonPushed, true);
            app.PlotDirectivityPatternButton.Text = 'Plot Directivity Pattern';
            app.TiltAxisDropDownLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAxisDropDownLabel.Text = 'Tilt Axis';
            app.TiltAxisDropDown = uidropdown(app.DirectionalAntennaTab);
            app.TiltAxisDropDown.Items = {'x', 'y'};
            app.TiltAxisDropDown.Value = 'x';
            app.TransmitterSitesListBoxLabel = uilabel(app.Panel);
            app.TransmitterSitesListBoxLabel.Text = {'Transmitter'; 'Sites'};
            app.TransmitterSitesListBox = uilistbox(app.Panel);
            app.TransmitterSitesListBox.Items = {};
            app.TransmitterSitesListBox.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.TransmitterSitesListBox.ClickedFcn = createCallbackFcn(app, @TransmitterSitesListBoxClicked, true);
            app.TransmitterSitesListBox.Value = {};
            app.RecieverSitesLabel = uilabel(app.Panel);
            app.RecieverSitesLabel.Text = {'Reciever '; 'Sites'};
            app.RecieverSitesListBox = uilistbox(app.Panel);
            app.RecieverSitesListBox.Items = {};
            app.RecieverSitesListBox.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.RecieverSitesListBox.ClickedFcn = createCallbackFcn(app, @RecieverSitesListBoxClicked, true);
            app.RecieverSitesListBox.Value = {};
            app.DatasetGenerationTab = uitab(app.TabGroup3);
            app.DatasetGenerationTab.Title = 'Dataset Generation';
            app.GenerateRandomSitesButton = uibutton(app.DatasetGenerationTab, 'push');
            app.GenerateRandomSitesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomSitesButtonPushed, true);
            app.GenerateRandomSitesButton.Text = 'Generate Random Sites';
            app.NumberofSampleEditFieldLabel = uilabel(app.DatasetGenerationTab);
            app.NumberofSampleEditFieldLabel.Enable = 'off';
            app.NumberofSampleEditFieldLabel.Text = 'Number of Sample ';
            app.NumberofSampleEditField = uieditfield(app.DatasetGenerationTab, 'numeric');
            app.NumberofSampleEditField.Limits = [1 Inf];
            app.NumberofSampleEditField.Editable = 'off';
            app.NumberofSampleEditField.Enable = 'off';
            app.NumberofSampleEditField.Value = 1;
            app.nEditFieldLabel = uilabel(app.DatasetGenerationTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.DatasetGenerationTab, 'numeric');
            app.nEditField.Limits = [1 Inf];
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 1;
            app.mEditFieldLabel = uilabel(app.DatasetGenerationTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.DatasetGenerationTab, 'numeric');
            app.mEditField.Limits = [1 Inf];
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 1;
            app.TransmitterPowerdBmEditFieldLabel = uilabel(app.DatasetGenerationTab);
            app.TransmitterPowerdBmEditFieldLabel.Text = 'Transmitter Power (dBm)';
            app.TransmitterPowerdBmEditField = uieditfield(app.DatasetGenerationTab, 'numeric');
            app.TransmitterPowerdBmEditField.Value = 10;
            app.GenerateDatasetButton = uibutton(app.DatasetGenerationTab, 'push');
            app.GenerateDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDatasetButtonPushed, true);
            app.GenerateDatasetButton.Text = 'Generate Dataset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizeAntennaCoverage_test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end