classdef RF_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CoverageAreaPanel_2            matlab.ui.container.Panel
        CoverageArea                   matlab.ui.control.UIAxes
        CoverageAreaPanel              matlab.ui.container.Panel
        CoverageAreaButton             matlab.ui.control.Button
        TransmitterDropDown            matlab.ui.control.DropDown
        TransmitterDropDownLabel       matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        los                            matlab.ui.control.UIAxes
        TransmittersandReceiversPanel  matlab.ui.container.Panel
        PlotLineofSightButton          matlab.ui.control.Button
        AddButton_3                    matlab.ui.control.Button
        AddReceiverDropDown            matlab.ui.control.DropDown
        AddReceiverDropDownLabel       matlab.ui.control.Label
        AddButton_2                    matlab.ui.control.Button
        AddTransmitterDropDown         matlab.ui.control.DropDown
        AddTransmitterLabel            matlab.ui.control.Label
        ReceiversListBox               matlab.ui.control.ListBox
        ReceiversListBoxLabel          matlab.ui.control.Label
        TransmittersListBox            matlab.ui.control.ListBox
        TransmittersListBoxLabel       matlab.ui.control.Label
        EnvironmentCanvasPanel         matlab.ui.container.Panel
        Canvas2                        matlab.ui.control.UIAxes
        PlatformsPanel                 matlab.ui.container.Panel
        TotalTowersEditField           matlab.ui.control.NumericEditField
        TotalTowersLabel               matlab.ui.control.Label
        TotalPlatformsEditField        matlab.ui.control.NumericEditField
        TotalPlatformsEditFieldLabel   matlab.ui.control.Label
        AddPlatformsDropDown           matlab.ui.control.DropDown
        AddPlatformsDropDownLabel      matlab.ui.control.Label
        AddButton                      matlab.ui.control.Button
        EnvironmentPropertiesPanel     matlab.ui.container.Panel
        PlatformsDropDown              matlab.ui.control.DropDown
        PlatformsDropDownLabel         matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ScenerioViewPanel              matlab.ui.container.Panel
        Canvas                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        totalTowers = 0 % Description
        totalPlatforms = 0 % Description
        platforms
        transmitters = []
        receivers = []
        fig
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            value = str2num(app.AddPlatformsDropDown.Value);
            if app.totalPlatforms == 0
                myFieldNames = {'ID', 'x', 'y', 'z', 'length', 'width', 'height'};
                T = array2table(zeros(0,7),'VariableNames',myFieldNames);

                % create a table with the data and field names
                app.platforms = T;
            end

            if value == 1
                app.totalTowers = app.totalTowers + 1;
                app.totalPlatforms = app.totalPlatforms + 1;
                % Add tower
                % Read the X, Y, and Z coordinates from the edit fields
                x = 1;
                y = 1;
                z = 1;

                % Define the vertices of the cube
                length = 1;
                width = 1;
                height = 1;
                vertices = [x-length/2 y-width/2 z-height/2;
                    x+length/2 y-width/2 z-height/2;
                    x+length/2 y+width/2 z-height/2;
                    x-length/2 y+width/2 z-height/2;
                    x-length/2 y-width/2 z+height/2;
                    x+length/2 y-width/2 z+height/2;
                    x+length/2 y+width/2 z+height/2;
                    x-length/2 y+width/2 z+height/2];

                % Define the faces of the cube
                faces = [1 2 3 4;
                    2 6 7 3;
                    4 3 7 8;
                    1 5 8 4;
                    1 2 6 5;
                    5 6 7 8];

                % Plot the cube on the canvas
                cube = patch('Parent', app.Canvas, 'Faces', faces, 'Vertices', vertices, 'FaceColor', 'blue', 'FaceAlpha', 0.5);
                tower_str = 'Tower_';
                tower_count = num2str(app.totalTowers);
                tower_id = string(strcat(tower_str,tower_count));
                % add a new row to the table
                new_row = {tower_id,x,y,z,length,width,height};
                T = [app.platforms; new_row];
                app.platforms = T;
            end

            app.fig = cube;

            x_all_platforms = app.platforms.x;
            y_all_platforms = app.platforms.y;
            z_all_platforms = app.platforms.z;
            plot(app.Canvas2, x_all_platforms, y_all_platforms, '*')

            app.TotalTowersEditField.Value = app.totalTowers;
            app.TotalPlatformsEditField.Value = app.totalPlatforms;

        end

        % Drop down opening function: PlatformsDropDown
        function PlatformsDropDownOpening(app, event)
            app.PlatformsDropDown.Items = app.platforms{:, 'ID'};
            app.PlatformsDropDown.ItemsData = app.platforms{:, 'ID'};
            %             app.PlatformsDropDown.Value = app.platforms{1, 'ID'};
        end

        % Value changed function: PlatformsDropDown
        function PlatformsDropDownValueChanged(app, event)
            value = app.PlatformsDropDown.Value;
            disp(app.platforms)
            row = find(strcmp(app.platforms.ID, (value)));
            disp(row)
            rowTable = app.platforms(row, :);
            app.XEditField.Value = rowTable.x;
            app.YEditField.Value = rowTable.y;
            app.ZEditField.Value = rowTable.z;
            app.LengthEditField.Value = rowTable.length;
            app.WidthEditField.Value = rowTable.width;
            app.HeightEditField.Value = rowTable.height;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Read the X, Y, and Z coordinates from the edit fields
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;

            % Define the vertices of the cube
            length = app.LengthEditField.Value;
            width = app.WidthEditField.Value;
            height = app.HeightEditField.Value;

            row = find(strcmp(app.platforms.ID, app.PlatformsDropDown.Value));
            rowTable = app.platforms(row, :);
            rowTable.x = app.XEditField.Value ;
            rowTable.y = app.YEditField.Value;
            rowTable.z = app.ZEditField.Value;
            rowTable.length = app.LengthEditField.Value  ;
            rowTable.width = app.WidthEditField.Value;
            rowTable.height = app.HeightEditField.Value;
            app.platforms(row, :) = rowTable;

            vertices = [x-length/2 y-width/2 z-height/2;
                x+length/2 y-width/2 z-height/2;
                x+length/2 y+width/2 z-height/2;
                x-length/2 y+width/2 z-height/2;
                x-length/2 y-width/2 z+height/2;
                x+length/2 y-width/2 z+height/2;
                x+length/2 y+width/2 z+height/2;
                x-length/2 y+width/2 z+height/2];

            % Define the faces of the cube
            faces = [1 2 3 4;
                2 6 7 3;
                4 3 7 8;
                1 5 8 4;
                1 2 6 5;
                5 6 7 8];

            % Plot the cube on the canvas
            cube = patch('Parent', app.Canvas, 'Faces', faces, 'Vertices', vertices, 'FaceColor', 'Blue', 'FaceAlpha', 0.5);
            x_all_platforms = app.platforms.x;
            y_all_platforms = app.platforms.y;
            z_all_platforms = app.platforms.z;
            plot(app.Canvas2, x_all_platforms, y_all_platforms, '*')
        end

        % Drop down opening function: AddTransmitterDropDown
        function AddTransmitterDropDownOpening(app, event)
            app.AddTransmitterDropDown.Items = app.platforms{:, 'ID'};
            app.AddTransmitterDropDown.ItemsData = app.platforms{:, 'ID'};

        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            value = app.AddTransmitterDropDown.Value;
            app.TransmittersListBox.Items{end+1} = num2str(value);
            app.transmitters = app.TransmittersListBox.Items;

        end

        % Drop down opening function: AddReceiverDropDown
        function AddReceiverDropDownOpening(app, event)
            app.AddReceiverDropDown.Items = app.platforms{:, 'ID'};
            app.AddReceiverDropDown.ItemsData = app.platforms{:, 'ID'};
        end

        % Button pushed function: AddButton_3
        function AddButton_3Pushed(app, event)
            value = app.AddReceiverDropDown.Value;
            app.ReceiversListBox.Items{end+1} = num2str(value);
            app.receivers = app.ReceiversListBox.Items;
        end

        % Button pushed function: PlotLineofSightButton
        function PlotLineofSightButtonPushed(app, event)
            t_x = [];
            t_y = [];
            t_z = [];

            r_x = [];
            r_y = [];
            r_z = [];

            for i = 1:length(app.transmitters)
                val = app.transmitters{i};
                row = find(strcmp(app.platforms.ID, (val)));
                rowTable = app.platforms(row, :);
                t_x = [t_x, rowTable.x];
                t_y = [t_y, rowTable.y];
                t_z = [t_z, rowTable.z];

            end

            for i = 1:length(app.receivers)
                val = app.receivers{i};
                row = find(strcmp(app.platforms.ID, (val)));
                rowTable = app.platforms(row, :);
                r_x = [r_x, rowTable.x];
                r_y = [r_y, rowTable.y];
                r_z = [r_z, rowTable.z];

            end

            %             tx = [];
            %             rx = [];
            %
            %             for i = 1:length(t_x)
            %                 tx1 = txsite("cartesian","AntennaPosition",[t_x(i); t_y(i); t_z(i)]);
            %                 tx = [tx, tx1];
            %             end
            %
            %             for i = 1:length(r_x)
            %                 rx1 = txsite("cartesian","AntennaPosition",[r_x(i); r_y(i); r_z(i)]);
            %                 rx = [rx, rx1];
            %             end
            %
            %             [los_status, los_start, los_end] = los(tx, rx);

            for j = 1:length(t_x)
                for i = 1:length(r_x)

                    % Plot the line of sight
                    plot3(app.los, [t_x(j), r_x(i)], [t_y(j), r_y(i)], [t_z(j), r_z(i)], 'g--', 'LineWidth', 2);
                    hold(app.los, 'on');
                end
            end

            for i = 1:length(t_x)

                % Highlight the transmitter node
                scatter3(app.los, t_x(i), t_y(i), t_z(i), 'filled', 'MarkerFaceColor', 'r');
                hold(app.los, 'on');
            end

            for i = 1:length(r_x)

                % Highlight the transmitter node
                scatter3(app.los, r_x(i), r_y(i), r_z(i), 'filled', 'MarkerFaceColor', 'g');
                hold(app.los, 'on');
            end


            disp(r_x)
            disp(r_y)
            disp(r_z)

            disp("transmitters")
            disp(t_x)
            disp(t_y)
            disp(t_z)

            hold off;


        end

        % Drop down opening function: TransmitterDropDown
        function TransmitterDropDownOpening(app, event)

            app.TransmitterDropDown.Items = app.transmitters;
            app.TransmitterDropDown.ItemsData = app.transmitters;

        end

        % Button pushed function: CoverageAreaButton
        function CoverageAreaButtonPushed(app, event)
            val = app.TransmitterDropDown.Value;
            disp("transmitter drop down val")
            disp(val)
            row = find(strcmp(app.platforms.ID, num2str(val)));
            rowTable = app.platforms(row, :);
            t_x =  rowTable.x;
            t_y =  rowTable.y;
            t_z =  rowTable.z;

            % Define the transmitter position
            transmitterPos = [t_x, t_y, t_z];

            % Define the transmission range
            transmissionRange = 100;

            % Generate a set of points within the transmission range
            numPoints = 100;
            theta = linspace(0, 2*pi, numPoints);
            rho = linspace(0, transmissionRange, numPoints);
            [theta, rho] = meshgrid(theta, rho);
            x = rho .* cos(theta);
            y = rho .* sin(theta);
            z = zeros(size(x));

            % Calculate the distance from each point to the transmitter
            distances = sqrt(sum((transmitterPos - [x(:), y(:), z(:)]).^2, 2));

            % Calculate the received power using the Two-Ray Ground propagation model
            receivedPower = 20 * log10(1 ./ distances);

            % Set a threshold received power to determine the coverage area
            thresholdPower = -50;  % Adjust this value as needed

            % Find the boundary points of the coverage area
            boundaryIndices = find(receivedPower >= thresholdPower);
            boundaryPoints = [x(boundaryIndices), y(boundaryIndices), z(boundaryIndices)];

            % Plot the boundary points to visualize the coverage area
            plot3(app.CoverageArea, boundaryPoints(:, 1), boundaryPoints(:, 2), boundaryPoints(:, 3), 'b.');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScenerioViewPanel = uipanel(app.UIFigure);
            app.ScenerioViewPanel.Title = 'Scenerio View';
            app.Canvas = uiaxes(app.ScenerioViewPanel);
            app.Canvas.XLim = [-20 20];
            app.Canvas.YLim = [-20 20];
            app.Canvas.ZLim = [-20 20];
            app.Canvas.XTickLabel = '';
            app.Canvas.YTickLabel = '';
            app.Canvas.ZTickLabel = '';
            colormap(app.Canvas, 'copper')
            app.EnvironmentPropertiesPanel = uipanel(app.UIFigure);
            app.EnvironmentPropertiesPanel.Title = 'Environment Properties';
            app.XEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.UpdateButton = uibutton(app.EnvironmentPropertiesPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PlatformsDropDownLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.PlatformsDropDownLabel.Text = 'Platforms';
            app.PlatformsDropDown = uidropdown(app.EnvironmentPropertiesPanel);
            app.PlatformsDropDown.Items = {};
            app.PlatformsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PlatformsDropDownOpening, true);
            app.PlatformsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlatformsDropDownValueChanged, true);
            app.PlatformsDropDown.Value = {};
            app.PlatformsPanel = uipanel(app.UIFigure);
            app.PlatformsPanel.Title = 'Platforms';
            app.AddButton = uibutton(app.PlatformsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddPlatformsDropDownLabel = uilabel(app.PlatformsPanel);
            app.AddPlatformsDropDownLabel.Text = 'Add Platforms';
            app.AddPlatformsDropDown = uidropdown(app.PlatformsPanel);
            app.AddPlatformsDropDown.Items = {'1. Towers', '2.', '3.'};
            app.AddPlatformsDropDown.ItemsData = {'1', '2', '3'};
            app.AddPlatformsDropDown.Value = '1';
            app.TotalPlatformsEditFieldLabel = uilabel(app.PlatformsPanel);
            app.TotalPlatformsEditFieldLabel.Text = 'Total Platforms';
            app.TotalPlatformsEditField = uieditfield(app.PlatformsPanel, 'numeric');
            app.TotalTowersLabel = uilabel(app.PlatformsPanel);
            app.TotalTowersLabel.Text = 'Total Towers';
            app.TotalTowersEditField = uieditfield(app.PlatformsPanel, 'numeric');
            app.EnvironmentCanvasPanel = uipanel(app.UIFigure);
            app.EnvironmentCanvasPanel.Title = 'Environment Canvas';
            app.Canvas2 = uiaxes(app.EnvironmentCanvasPanel);
            xlabel(app.Canvas2, 'X')
            ylabel(app.Canvas2, 'Y')
            zlabel(app.Canvas2, 'Z')
            app.Canvas2.XLim = [-10 10];
            app.Canvas2.YLim = [-10 10];
            app.Canvas2.ZLim = [-10 10];
            app.Canvas2.XLimitMethod = 'padded';
            app.Canvas2.YLimitMethod = 'padded';
            app.Canvas2.ZLimitMethod = 'padded';
            app.TransmittersandReceiversPanel = uipanel(app.UIFigure);
            app.TransmittersandReceiversPanel.Title = 'Transmitters and Receivers';
            app.TransmittersListBoxLabel = uilabel(app.TransmittersandReceiversPanel);
            app.TransmittersListBoxLabel.Text = 'Transmitters';
            app.TransmittersListBox = uilistbox(app.TransmittersandReceiversPanel);
            app.TransmittersListBox.Items = {};
            app.TransmittersListBox.Value = {};
            app.ReceiversListBoxLabel = uilabel(app.TransmittersandReceiversPanel);
            app.ReceiversListBoxLabel.Text = 'Receivers';
            app.ReceiversListBox = uilistbox(app.TransmittersandReceiversPanel);
            app.ReceiversListBox.Items = {};
            app.ReceiversListBox.Value = {};
            app.AddTransmitterLabel = uilabel(app.TransmittersandReceiversPanel);
            app.AddTransmitterLabel.Text = {'Add '; 'Transmitter'};
            app.AddTransmitterDropDown = uidropdown(app.TransmittersandReceiversPanel);
            app.AddTransmitterDropDown.Items = {};
            app.AddTransmitterDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AddTransmitterDropDownOpening, true);
            app.AddTransmitterDropDown.Value = {};
            app.AddButton_2 = uibutton(app.TransmittersandReceiversPanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.AddReceiverDropDownLabel = uilabel(app.TransmittersandReceiversPanel);
            app.AddReceiverDropDownLabel.Text = {'Add '; 'Receiver'};
            app.AddReceiverDropDown = uidropdown(app.TransmittersandReceiversPanel);
            app.AddReceiverDropDown.Items = {};
            app.AddReceiverDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AddReceiverDropDownOpening, true);
            app.AddReceiverDropDown.Value = {};
            app.AddButton_3 = uibutton(app.TransmittersandReceiversPanel, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButton_3Pushed, true);
            app.AddButton_3.Text = 'Add';
            app.PlotLineofSightButton = uibutton(app.TransmittersandReceiversPanel, 'push');
            app.PlotLineofSightButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLineofSightButtonPushed, true);
            app.PlotLineofSightButton.Text = 'Plot Line of Sight';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.los = uiaxes(app.Panel);
            title(app.los, 'Line of Sight')
            xlabel(app.los, 'X')
            ylabel(app.los, 'Y')
            zlabel(app.los, 'Z')
            app.CoverageAreaPanel = uipanel(app.UIFigure);
            app.CoverageAreaPanel.Title = 'Coverage Area';
            app.TransmitterDropDownLabel = uilabel(app.CoverageAreaPanel);
            app.TransmitterDropDownLabel.Text = 'Transmitter';
            app.TransmitterDropDown = uidropdown(app.CoverageAreaPanel);
            app.TransmitterDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TransmitterDropDownOpening, true);
            app.CoverageAreaButton = uibutton(app.CoverageAreaPanel, 'push');
            app.CoverageAreaButton.ButtonPushedFcn = createCallbackFcn(app, @CoverageAreaButtonPushed, true);
            app.CoverageAreaButton.Text = 'Coverage Area';
            app.CoverageAreaPanel_2 = uipanel(app.UIFigure);
            app.CoverageAreaPanel_2.Title = 'Coverage Area';
            app.CoverageArea = uiaxes(app.CoverageAreaPanel_2);
            title(app.CoverageArea, 'Coverage Area')
            xlabel(app.CoverageArea, 'X')
            ylabel(app.CoverageArea, 'Y')
            zlabel(app.CoverageArea, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RF_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end