classdef new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DirectivityPlotPanel            matlab.ui.container.Panel
        DirectivityPlot                 matlab.ui.control.UIAxes
        MapPanel                        matlab.ui.container.Panel
        TransmittersandReceiversPanel   matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        CoverageAreaTab                 matlab.ui.container.Tab
        PropagationModelDropDown        matlab.ui.control.DropDown
        PropagationModelDropDownLabel   matlab.ui.control.Label
        PlotIdealcoverageAreaButton     matlab.ui.control.Button
        ReceiverSentivityEditField      matlab.ui.control.NumericEditField
        ReceiverSentivityEditFieldLabel  matlab.ui.control.Label
        DirectionalAntennaTab           matlab.ui.container.Tab
        TiltAxisDropDown                matlab.ui.control.DropDown
        TiltAxisDropDownLabel           matlab.ui.control.Label
        PlotDirectivityPatternButton    matlab.ui.control.Button
        TiltAngleEditField              matlab.ui.control.NumericEditField
        TiltAngleEditFieldLabel         matlab.ui.control.Label
        ReceiverSitesListBox            matlab.ui.control.ListBox
        ReceiverSitesListBoxLabel       matlab.ui.control.Label
        TransmitterSitesListBox         matlab.ui.control.ListBox
        TransmitterSitesLabel           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ReceiverTab                     matlab.ui.container.Tab
        ReceiverAddSiteButton           matlab.ui.control.Button
        ReceiverNameofSiteEditField     matlab.ui.control.EditField
        NameofSiteEditField_2Label      matlab.ui.control.Label
        ReceiverLongitudeEditField      matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        ReceiverLatitudeEditField       matlab.ui.control.NumericEditField
        LatitudeEditField_2Label        matlab.ui.control.Label
        TransmitterTab                  matlab.ui.container.Tab
        TransmitterAddSiteButton        matlab.ui.control.Button
        TransmitterPowerwattsEditField  matlab.ui.control.NumericEditField
        TransmitterPowerwattsEditFieldLabel  matlab.ui.control.Label
        AntennaHeightmetersEditField    matlab.ui.control.NumericEditField
        AntennaHeightLabel              matlab.ui.control.Label
        TransmitterFrequencye9HzEditField  matlab.ui.control.NumericEditField
        Frequencye9Label                matlab.ui.control.Label
        TransmitterNameofSiteEditField  matlab.ui.control.EditField
        NameofSiteEditFieldLabel        matlab.ui.control.Label
        TransmitterLongitudeEditField   matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        TransmiterLatitudeEditField     matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Latitudes = []% Description
        Longitudes = []% Description
        transmitterLat = []
        receiverLat = []
        transmitterLon = []
        receiverLon = []
        transmitters = []
        receivers = []
        geoPlotAxes
        SiteViewer
        txa
        rxsa
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransmitterAddSiteButton
        function TransmitterAddSiteButtonPushed(app, event)
            
            num = app.TransmitterFrequencye9HzEditField.Value;
            str = 'e9';
            fq = str2double([num2str(num) str]);
            disp((fq))
            tx = txsite("Name",(app.TransmitterNameofSiteEditField.Value), ...
                "Latitude",(app.TransmiterLatitudeEditField.Value), ...
                "Longitude",(app.TransmitterLongitudeEditField.Value), ...
                "Antenna",design(dipole,(fq)), ...
                "AntennaHeight",app.AntennaHeightmetersEditField.Value, ...        % Units: meters
                "TransmitterFrequency",fq, ... % Units: Hz
                "TransmitterPower",app.TransmitterPowerwattsEditField.Value);        % Units: Watts
            app.transmitters = [app.transmitters app.TransmitterNameofSiteEditField.Value];
            app.transmitterLat = [app.transmitterLat (app.TransmiterLatitudeEditField.Value)];
            app.transmitterLon = [app.transmitterLon (app.TransmitterLongitudeEditField.Value)];
            app.TransmitterSitesListBox.Items{end+1} = app.TransmitterNameofSiteEditField.Value;
            geoAxes = geoaxes(app.MapPanel);
            app.geoPlotAxes = geoAxes;
            app.Latitudes = [app.Latitudes (app.TransmiterLatitudeEditField.Value)];
            app.Longitudes = [app.Longitudes (app.TransmitterLongitudeEditField.Value)];
            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(geoAxes, 'on');
%             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            geobasemap(app.geoPlotAxes, 'streets')


        end

        % Button pushed function: ReceiverAddSiteButton
        function ReceiverAddSiteButtonPushed(app, event)
            app.receivers = [app.receivers app.ReceiverNameofSiteEditField.Value];
            app.receiverLat = [app.receiverLat app.ReceiverLatitudeEditField.Value];
            app.receiverLon = [app.receiverLon app.ReceiverLongitudeEditField.Value];
            app.ReceiverSitesListBox.Items{end+1} = app.ReceiverNameofSiteEditField.Value;
            app.Latitudes = [app.Latitudes (app.ReceiverLatitudeEditField.Value)];
            app.Longitudes = [app.Longitudes (app.ReceiverLongitudeEditField.Value)];
            
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
%             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            geobasemap(app.geoPlotAxes, 'streets')

        end

        % Button pushed function: PlotIdealcoverageAreaButton
        function PlotIdealcoverageAreaButtonPushed(app, event)
            num = app.TransmitterFrequencye9HzEditField.Value;
            str = 'e9';
            fq = str2double([num2str(num) str]);
            disp((fq))
            tx = txsite("Name",(app.TransmitterNameofSiteEditField.Value), ...
                "Latitude",(app.TransmiterLatitudeEditField.Value), ...
                "Longitude",(app.TransmitterLongitudeEditField.Value), ...
                "Antenna",design(dipole,(fq)), ...
                "AntennaHeight",app.AntennaHeightmetersEditField.Value, ...        % Units: meters
                "TransmitterFrequency",fq, ... % Units: Hz
                "TransmitterPower",app.TransmitterPowerwattsEditField.Value);        % Units: Watts
            rxs = rxsite("Name",app.receivers, ...
                "Latitude",app.receiverLat, ...
                "Longitude",app.receiverLon, ...
                "Antenna",design(dipole,tx.TransmitterFrequency), ...
                "ReceiverSensitivity",app.ReceiverSentivityEditField.Value); % Units: dBm
            app.txa = tx;

            app.rxsa = rxs;

            viewer = siteviewer;
            show(tx)
            show(rxs)
            viewer.Basemap = "openstreetmap";

            coverage(tx,app.PropagationModelDropDown.Value, ...
                "SignalStrengths",app.ReceiverSentivityEditField.Value)
            sc = [0 0.3 0];
            link(rxs,tx,app.PropagationModelDropDown.Value,"SuccessColor",sc)

%             % Get the content of the figure as an image
%             tempFigure = figure('Visible', 'off');
%             axesObj = getAxes(viewer);
%             figImage = getframe(axesObj);
%             close(tempFigure);
% 
%             
%             imageData = figImage.cdata;
% 
%             % Display the image in the UIAxes
%             imshow(imageData, 'Parent', app.SiteViewerPanel);
        end

        % Button pushed function: PlotDirectivityPatternButton
        function PlotDirectivityPatternButtonPushed(app, event)
            % Define Yagi-Uda antenna designed for transmitter frequency
%             num = app.TransmitterFrequencye9HzEditField.Value;
%             str = 'e9';
%             fq = str2double([num2str(num) str]);
%             disp((fq))
%             tx = txsite("Name",(app.TransmitterNameofSiteEditField.Value), ...
%                 "Latitude",(app.TransmiterLatitudeEditField.Value), ...
%                 "Longitude",(app.TransmitterLongitudeEditField.Value), ...
%                 "Antenna",design(dipole,(fq)), ...
%                 "AntennaHeight",app.AntennaHeightmetersEditField.Value, ...        % Units: meters
%                 "TransmitterFrequency",fq, ... % Units: Hz
%                 "TransmitterPower",app.TransmitterPowerwattsEditField.Value);        % Units: Watts
            yagiAnt = design(yagiUda,app.txa.TransmitterFrequency);

            % Tilt antenna to direct radiation in XY-plane (i.e. geographic azimuth)
            disp(app.TiltAngleEditField.Value)
            disp(app.TiltAxisDropDown.Value)

            yagiAnt.Tilt = app.TiltAngleEditField.Value;
            yagiAnt.TiltAxis = app.TiltAxisDropDown.Value;
    
            f = figure;

            % Show directivity pattern
            patternAzimuth(yagiAnt,app.txa.TransmitterFrequency);

            % Get the content of the figure as an image
            figImage = getframe(f);
            imageData = figImage.cdata;

            % Display the image in the UIAxes
            imshow(imageData, 'Parent', app.DirectivityPlot);
            app.txa.Antenna = yagiAnt;

            % Point main beam toward Boston, MA by assigning azimuth angle between
            % transmitter location and Boston receiver location
            app.txa.AntennaAngle = angle(app.txa, app.rxsa(1));

            % Define signal strengths from sensitivity to -60 dB
            sigStrengths = app.ReceiverSentivityEditField.Value:5:-60;
            sc = [0 0.3 0];

            % Update coverage map
            coverage(app.txa,"rain","SignalStrengths",sigStrengths)

            link(app.rxsa,app.txa,"rain","SuccessColor",sc)

            % Get the content of the figure as an image
%             figImage = getframe(viewer);
%             imageData = figImage.cdata;
% 
%             % Display the image in the UIAxes
%             imshow(imageData, 'Parent', app.SiteViewerPanel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransmittersandReceiversPanel = uipanel(app.UIFigure);
            app.TransmittersandReceiversPanel.Title = 'Transmitters and Receivers';
            app.TabGroup = uitabgroup(app.TransmittersandReceiversPanel);
            app.TransmitterTab = uitab(app.TabGroup);
            app.TransmitterTab.Title = 'Transmitter';
            app.LatitudeEditFieldLabel = uilabel(app.TransmitterTab);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.TransmiterLatitudeEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.TransmiterLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmiterLatitudeEditField.Value = 42.3001;
            app.LongitudeEditFieldLabel = uilabel(app.TransmitterTab);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.TransmitterLongitudeEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.TransmitterLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmitterLongitudeEditField.Value = -71.3504;
            app.NameofSiteEditFieldLabel = uilabel(app.TransmitterTab);
            app.NameofSiteEditFieldLabel.Text = 'Name of Site';
            app.TransmitterNameofSiteEditField = uieditfield(app.TransmitterTab, 'text');
            app.TransmitterNameofSiteEditField.Value = 'MathWorks';
            app.Frequencye9Label = uilabel(app.TransmitterTab);
            app.Frequencye9Label.Text = 'Frequency (*e9 Hz)';
            app.TransmitterFrequencye9HzEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.TransmitterFrequencye9HzEditField.Value = 6;
            app.AntennaHeightLabel = uilabel(app.TransmitterTab);
            app.AntennaHeightLabel.Text = 'Antenna Height (meters)';
            app.AntennaHeightmetersEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.AntennaHeightmetersEditField.Value = 60;
            app.TransmitterPowerwattsEditFieldLabel = uilabel(app.TransmitterTab);
            app.TransmitterPowerwattsEditFieldLabel.Text = 'Transmitter Power (watts)';
            app.TransmitterPowerwattsEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.TransmitterPowerwattsEditField.Value = 15;
            app.TransmitterAddSiteButton = uibutton(app.TransmitterTab, 'push');
            app.TransmitterAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitterAddSiteButtonPushed, true);
            app.TransmitterAddSiteButton.Text = 'Add Site';
            app.ReceiverTab = uitab(app.TabGroup);
            app.ReceiverTab.Title = 'Receiver';
            app.LatitudeEditField_2Label = uilabel(app.ReceiverTab);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.ReceiverLatitudeEditField = uieditfield(app.ReceiverTab, 'numeric');
            app.ReceiverLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLatitudeEditField.Value = 42.3601;
            app.LongitudeEditField_2Label = uilabel(app.ReceiverTab);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.ReceiverLongitudeEditField = uieditfield(app.ReceiverTab, 'numeric');
            app.ReceiverLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLongitudeEditField.Value = -71.0589;
            app.NameofSiteEditField_2Label = uilabel(app.ReceiverTab);
            app.NameofSiteEditField_2Label.Text = 'Name of Site';
            app.ReceiverNameofSiteEditField = uieditfield(app.ReceiverTab, 'text');
            app.ReceiverNameofSiteEditField.Value = 'Boston';
            app.ReceiverAddSiteButton = uibutton(app.ReceiverTab, 'push');
            app.ReceiverAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiverAddSiteButtonPushed, true);
            app.ReceiverAddSiteButton.Text = 'Add Site';
            app.TransmitterSitesLabel = uilabel(app.TransmittersandReceiversPanel);
            app.TransmitterSitesLabel.Text = {'Transmitter'; 'Sites'};
            app.TransmitterSitesListBox = uilistbox(app.TransmittersandReceiversPanel);
            app.TransmitterSitesListBox.Items = {};
            app.TransmitterSitesListBox.Value = {};
            app.ReceiverSitesListBoxLabel = uilabel(app.TransmittersandReceiversPanel);
            app.ReceiverSitesListBoxLabel.Text = {'Receiver'; 'Sites'};
            app.ReceiverSitesListBox = uilistbox(app.TransmittersandReceiversPanel);
            app.ReceiverSitesListBox.Items = {};
            app.ReceiverSitesListBox.Value = {};
            app.TabGroup2 = uitabgroup(app.TransmittersandReceiversPanel);
            app.CoverageAreaTab = uitab(app.TabGroup2);
            app.CoverageAreaTab.Title = 'Coverage Area';
            app.ReceiverSentivityEditFieldLabel = uilabel(app.CoverageAreaTab);
            app.ReceiverSentivityEditFieldLabel.Text = 'Receiver Sentivity';
            app.ReceiverSentivityEditField = uieditfield(app.CoverageAreaTab, 'numeric');
            app.ReceiverSentivityEditField.Value = -90;
            app.PlotIdealcoverageAreaButton = uibutton(app.CoverageAreaTab, 'push');
            app.PlotIdealcoverageAreaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIdealcoverageAreaButtonPushed, true);
            app.PlotIdealcoverageAreaButton.Text = 'Plot Ideal coverage Area';
            app.PropagationModelDropDownLabel = uilabel(app.CoverageAreaTab);
            app.PropagationModelDropDownLabel.Text = 'Propagation Model';
            app.PropagationModelDropDown = uidropdown(app.CoverageAreaTab);
            app.PropagationModelDropDown.Items = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rain', 'raytracing'};
            app.PropagationModelDropDown.ItemsData = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rain', 'raytracing'};
            app.PropagationModelDropDown.Value = 'freespace';
            app.DirectionalAntennaTab = uitab(app.TabGroup2);
            app.DirectionalAntennaTab.Title = 'Directional Antenna';
            app.TiltAngleEditFieldLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAngleEditFieldLabel.Text = 'Tilt Angle';
            app.TiltAngleEditField = uieditfield(app.DirectionalAntennaTab, 'numeric');
            app.TiltAngleEditField.Value = 90;
            app.PlotDirectivityPatternButton = uibutton(app.DirectionalAntennaTab, 'push');
            app.PlotDirectivityPatternButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDirectivityPatternButtonPushed, true);
            app.PlotDirectivityPatternButton.Text = 'Plot Directivity Pattern';
            app.TiltAxisDropDownLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAxisDropDownLabel.Text = 'Tilt Axis';
            app.TiltAxisDropDown = uidropdown(app.DirectionalAntennaTab);
            app.TiltAxisDropDown.Items = {'x', 'y'};
            app.TiltAxisDropDown.Value = 'x';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.DirectivityPlotPanel = uipanel(app.UIFigure);
            app.DirectivityPlotPanel.Title = 'Directivity Plot';
            app.DirectivityPlot = uiaxes(app.DirectivityPlotPanel);
            app.DirectivityPlot.XAxisLocation = 'origin';
            app.DirectivityPlot.XTick = [];
            app.DirectivityPlot.YAxisLocation = 'origin';
            app.DirectivityPlot.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end