classdef test_app_for_dataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Open file dialog for the user to select a file
            [filename, filepath] = uigetfile('*.csv', 'Select File');

            % Read the latitude and longitude data from the file
            data = csvread(fullfile(filepath, filename));
            latitudes = data(:, 1);
            longitudes = data(:, 2);

            % Define transmitter parameters
            transmitterPower = 10; % dBm
            frequency = 900e6; % Hz
            propagationModel = 'FreeSpace'; % or any other propagation model

            % Calculate the coverage areas for all the transmitter locations
            coverageAreas = {};
            for i = 1:numel(latitudes)
                transmitterLocation = [latitudes(i), longitudes(i)];
                range = propagationRange(transmitterLocation, transmitterPower, frequency, propagationModel);
                coverageArea = geoshape(range, transmitterLocation);
                coverageAreas{i} = coverageArea;
            end

            % Plot the coverage areas on a map using geoplot
            geoplot(app.UIAxes, coverageAreas, 'FaceAlpha', 0.5, 'FaceColor', 'green');
            geolimits(app.UIAxes, [min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_app_for_dataset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end